classdef lab_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ManipulatorviewPanel       matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        IndipendentvariablesPanel  matlab.ui.container.Panel
        l_3mSlider                 matlab.ui.control.Slider
        l_3Label                   matlab.ui.control.Label
        l_2mSlider                 matlab.ui.control.Slider
        l_2Label                   matlab.ui.control.Label
        l_1mSlider                 matlab.ui.control.Slider
        l_1Label                   matlab.ui.control.Label
        theta_2radSlider           matlab.ui.control.Slider
        theta_2Label               matlab.ui.control.Label
        theta_1radSlider           matlab.ui.control.Slider
        theta_1Label               matlab.ui.control.Label
    end


    methods (Access = private)
        
        function trasformation(app, theta_1, theta_2, l_1, l_2, l_3)
            
            H_10 = [...
                    cos(theta_1) -sin(theta_1) 0 0;...
                    sin(theta_1) cos(theta_1) 0 0; ...
                    0 0 1 0;...
                    0 0 0 1;...
                    ];
            H_21 = [...
                    1 0 0 0;...
                    0 1 0 0;...
                    0 0 1 l_1;...
                    0 0 0 1;...
                    ];
            H_32 = [...
                    1 0 0 l_2;...
                    0 1 0 0;...
                    0 0 1 0;...
                    0 0 0 1;...
                    ];
            H_43 = [...
                    cos(-theta_2) 0 sin(-theta_2) l_3;...
                    0 1 0 0;...
                    -sin(-theta_2) 0 cos(-theta_2) 0;...
                    0 0 0 1;...
                    ];
            H_54 = [...
                    1 0 0 l_3;...
                    0 1 0 0;...
                    0 0 1 0;...
                    0 0 0 1;...
                    ];
            
            hold(app.UIAxes, 'off')
            plot3(app.UIAxes,[0,0],[0,0],[0,0])
            hold(app.UIAxes, 'on')
            grid(app.UIAxes, 'on');
            plotReferenceSystem(app.UIAxes, [0, 0, 0], [1,0,0], [0,1,0], [0,0,1]);

            P_0 = [0, 0, 0, 1]';

            [P_0, xyz_0] = transformReferenceSystem(H_10);
            plotReferenceSystem(app.UIAxes, P_0, xyz_0(:,1), xyz_0(:,2), xyz_0(:,3),'\theta_1',"z");
            [P_1, xyz_1] = transformReferenceSystem(H_10*H_21);
            plotReferenceSystem(app.UIAxes, P_1, xyz_1(:,1), xyz_1(:,2), xyz_1(:,3));
            [P_2, xyz_2] = transformReferenceSystem(H_10*H_21*H_32);
            plotReferenceSystem(app.UIAxes, P_2, xyz_2(:,1), xyz_2(:,2), xyz_2(:,3));
            [P_3, xyz_3] = transformReferenceSystem(H_10*H_21*H_32*H_43);
            plotReferenceSystem(app.UIAxes, P_3, xyz_3(:,1), xyz_3(:,2), xyz_3(:,3),'\theta_2',"y");
            [P_4, xyz_4] = transformReferenceSystem(H_10*H_21*H_32*H_43*H_54);
            
            plotLink(app.UIAxes, P_0, P_1,'l_1');
            plotLink(app.UIAxes, P_1, P_2,'l_2');
            plotLink(app.UIAxes, P_2, P_3);
            plotLink(app.UIAxes, P_3, P_4,'l_3');

            
            title(app.UIAxes, '');
            axis(app.UIAxes,'equal')
            bounds = axis(app.UIAxes); 

            margin = 0.5;
            xRange = bounds(2) - bounds(1);
            yRange = bounds(4) - bounds(3);
            zRange = bounds(6) - bounds(5);
            xlim(app.UIAxes, [bounds(1) - margin*xRange, bounds(2) + margin*xRange]);
            ylim(app.UIAxes, [bounds(3) - margin*yRange, bounds(4) + margin*yRange]);
            zlim(app.UIAxes, [bounds(5) - margin*zRange, bounds(6) + margin*zRange]);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("robotics_library/")
            trasformation(app, app.theta_1radSlider.Value, app.theta_2radSlider.Value, app.l_1mSlider.Value, app.l_2mSlider.Value, app.l_3mSlider.Value)

        end

        % Value changing function: l_1mSlider
        function l_1mSliderValueChanging(app, event)
            trasformation(app, app.theta_1radSlider.Value, app.theta_2radSlider.Value, app.l_1mSlider.Value, app.l_2mSlider.Value, app.l_3mSlider.Value)
        
        end

        % Value changing function: theta_1radSlider
        function theta_1radSliderValueChanging(app, event)
            trasformation(app, app.theta_1radSlider.Value, app.theta_2radSlider.Value, app.l_1mSlider.Value, app.l_2mSlider.Value, app.l_3mSlider.Value)
        
        end

        % Value changing function: theta_2radSlider
        function theta_2radSliderValueChanging(app, event)
            trasformation(app, app.theta_1radSlider.Value, app.theta_2radSlider.Value, app.l_1mSlider.Value, app.l_2mSlider.Value, app.l_3mSlider.Value)
        
        end

        % Value changing function: l_2mSlider
        function l_2mSliderValueChanging(app, event)
            trasformation(app, app.theta_1radSlider.Value, app.theta_2radSlider.Value, app.l_1mSlider.Value, app.l_2mSlider.Value, app.l_3mSlider.Value)
        
        end

        % Value changing function: l_3mSlider
        function l_3mSliderValueChanging(app, event)
            trasformation(app, app.theta_1radSlider.Value, app.theta_2radSlider.Value, app.l_1mSlider.Value, app.l_2mSlider.Value, app.l_3mSlider.Value)
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IndipendentvariablesPanel = uipanel(app.UIFigure);
            app.IndipendentvariablesPanel.Title = 'Indipendent variables';
            app.theta_1Label = uilabel(app.IndipendentvariablesPanel);
            app.theta_1Label.Interpreter = 'latex';
            app.theta_1Label.Text = '$\theta_1$ [rad]';
            app.theta_1radSlider = uislider(app.IndipendentvariablesPanel);
            app.theta_1radSlider.Limits = [0 3];
            app.theta_1radSlider.ValueChangingFcn = createCallbackFcn(app, @theta_1radSliderValueChanging, true);
            app.theta_1radSlider.Value = 1;
            app.theta_2Label = uilabel(app.IndipendentvariablesPanel);
            app.theta_2Label.Interpreter = 'latex';
            app.theta_2Label.Text = '$\theta_2$ [rad]';
            app.theta_2radSlider = uislider(app.IndipendentvariablesPanel);
            app.theta_2radSlider.Limits = [0 1.5];
            app.theta_2radSlider.ValueChangingFcn = createCallbackFcn(app, @theta_2radSliderValueChanging, true);
            app.theta_2radSlider.Value = 0.5;
            app.l_1Label = uilabel(app.IndipendentvariablesPanel);
            app.l_1Label.Interpreter = 'latex';
            app.l_1Label.Text = '$l_1$ [m]';
            app.l_1mSlider = uislider(app.IndipendentvariablesPanel);
            app.l_1mSlider.Limits = [2 5];
            app.l_1mSlider.ValueChangingFcn = createCallbackFcn(app, @l_1mSliderValueChanging, true);
            app.l_1mSlider.Value = 2;
            app.l_2Label = uilabel(app.IndipendentvariablesPanel);
            app.l_2Label.Interpreter = 'latex';
            app.l_2Label.Text = '$l_2$ [m]';
            app.l_2mSlider = uislider(app.IndipendentvariablesPanel);
            app.l_2mSlider.Limits = [4 6];
            app.l_2mSlider.ValueChangingFcn = createCallbackFcn(app, @l_2mSliderValueChanging, true);
            app.l_2mSlider.Value = 4;
            app.l_3Label = uilabel(app.IndipendentvariablesPanel);
            app.l_3Label.Interpreter = 'latex';
            app.l_3Label.Text = '$l_3$ [m]';
            app.l_3mSlider = uislider(app.IndipendentvariablesPanel);
            app.l_3mSlider.Limits = [1 2];
            app.l_3mSlider.ValueChangingFcn = createCallbackFcn(app, @l_3mSliderValueChanging, true);
            app.l_3mSlider.Value = 1;
            app.ManipulatorviewPanel = uipanel(app.UIFigure);
            app.ManipulatorviewPanel.Title = 'Manipulator view';
            app.UIAxes = uiaxes(app.ManipulatorviewPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end