classdef lab_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PlotPanel                  matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        IndipendentvariablesPanel  matlab.ui.container.Panel
        l_1Slider                  matlab.ui.control.Slider
        l_1SliderLabel             matlab.ui.control.Label
        theta_2Slider              matlab.ui.control.Slider
        theta_2SliderLabel         matlab.ui.control.Label
        theta_1Slider              matlab.ui.control.Slider
        theta_1SliderLabel         matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function transformation(app, theta_1, theta_2, l_1)
            h_0 = 1.5
            h_1 = 1
            h_2 = 2

            H_10 = [...
                    cos(theta_1) -sin(theta_1) 0 0;...
                    sin(theta_1) cos(theta_1) 0 0; ...
                    0 0 1 h_0;...
                    0 0 0 1];
            H_21 = [...
                    cos(-theta_2) 0 sin(-theta_2) 0;...
                    0 1 0 h_1;...
                    -sin(-theta_2) 0 cos(-theta_2) 0;...
                    0 0 0 1];
            H_32 = [...
                    1 0 0 0;...
                    0 1 0 h_2;...
                    0 0 1 0;...
                    0 0 0 1];
            H_43 = [...
                    1 0 0 0;...
                    0 1 0 0;...
                    0 0 1 l_1;...
                    0 0 0 1];  

            origin = [0 0 0]';
            x_0 = [1 0 0 1]';
            y_0 = [0 1 0 1]';
            z_0 = [0 0 1 1]';

            x_1 = H_10 * x_0;
            y_1 = H_10 * y_0;
            z_1 = H_10 * z_0;

            x_2 = H_10*H_21* x_0;
            y_2 = H_10*H_21* y_0;
            z_2 = H_10*H_21* z_0;

            x_3 = H_10*H_21*H_32* x_0;
            y_3 = H_10*H_21*H_32* y_0;
            z_3 = H_10*H_21*H_32* z_0;
            
            x_4 = H_10*H_21*H_32*H_43*x_0;
            y_4 = H_10*H_21*H_32*H_43* y_0;
            z_4 = H_10*H_21*H_32*H_43* z_0;

            P_0 = [0 0 0 1]';
            P_1 = H_10*P_0;
            P_2 = H_10*H_21*P_0;
            P_3 = H_10*H_21*H_32*P_0;
            P_4 = H_10*H_21*H_32*H_43*P_0;

            hold(app.UIAxes, 'off')
            % plot3(app.UIAxes,[origin(1), x_0(1)],[origin(2), x_0(2)],[origin(3), x_0(3)])
            % plot3(app.UIAxes,[origin(1), y_0(1)],[origin(2), y_0(2)],[origin(3), y_0(3)])
            % plot3(app.UIAxes,[origin(1), z_0(1)],[origin(2), z_0(2)],[origin(3), z_0(3)])

            quiver3(app.UIAxes, origin(1), origin(2), origin(3), x_0(1)-origin(1), x_0(2)-origin(2), x_0(3)-origin(3), 'r', 'LineWidth', 2)
            hold(app.UIAxes, 'on')
            quiver3(app.UIAxes, origin(1), origin(2), origin(3), y_0(1)-origin(1), y_0(2)-origin(2), y_0(3)-origin(3), 'g', 'LineWidth', 2)
            quiver3(app.UIAxes, origin(1), origin(2), origin(3), z_0(1)-origin(1), z_0(2)-origin(2), z_0(3)-origin(3), 'b', 'LineWidth', 2)

            quiver3(app.UIAxes, P_1(1), P_1(2), P_1(3), x_1(1)-P_1(1), x_1(2)-P_1(2), x_1(3)-P_1(3), 'r', 'LineWidth', 2)
            quiver3(app.UIAxes, P_1(1), P_1(2), P_1(3), y_1(1)-P_1(1), y_1(2)-P_1(2), y_1(3)-P_1(3), 'g', 'LineWidth', 2)
            quiver3(app.UIAxes, P_1(1), P_1(2), P_1(3), z_1(1)-P_1(1), z_1(2)-P_1(2), z_1(3)-P_1(3), 'b', 'LineWidth', 2)

            quiver3(app.UIAxes, P_2(1), P_2(2), P_2(3), x_2(1)-P_2(1), x_2(2)-P_2(2), x_2(3)-P_2(3), 'r', 'LineWidth', 2)
            quiver3(app.UIAxes, P_2(1), P_2(2), P_2(3), y_2(1)-P_2(1), y_2(2)-P_2(2), y_2(3)-P_2(3), 'g', 'LineWidth', 2)
            quiver3(app.UIAxes, P_2(1), P_2(2), P_2(3), z_2(1)-P_2(1), z_2(2)-P_2(2), z_2(3)-P_2(3), 'b', 'LineWidth', 2)

            quiver3(app.UIAxes, P_3(1), P_3(2), P_3(3), x_3(1)-P_3(1), x_3(2)-P_3(2), x_3(3)-P_3(3), 'r', 'LineWidth', 2)
            quiver3(app.UIAxes, P_3(1), P_3(2), P_3(3), y_3(1)-P_3(1), y_3(2)-P_3(2), y_3(3)-P_3(3), 'g', 'LineWidth', 2)
            quiver3(app.UIAxes, P_3(1), P_3(2), P_3(3), z_3(1)-P_3(1), z_3(2)-P_3(2), z_3(3)-P_3(3), 'b', 'LineWidth', 2)


            quiver3(app.UIAxes, P_4(1), P_4(2), P_4(3), x_4(1)-P_4(1), x_4(2)-P_4(2), x_4(3)-P_4(3), 'r', 'LineWidth', 2)
            quiver3(app.UIAxes, P_4(1), P_4(2), P_4(3), y_4(1)-P_4(1), y_4(2)-P_4(2), y_4(3)-P_4(3), 'g', 'LineWidth', 2)
            quiver3(app.UIAxes, P_4(1), P_4(2), P_4(3), z_4(1)-P_4(1), z_4(2)-P_4(2), z_4(3)-P_4(3), 'b', 'LineWidth', 2)


            view(app.UIAxes, 45,30)
            xlim(app.UIAxes, [-5,5])
            ylim(app.UIAxes, [-5,5])
            zlim(app.UIAxes, [-0.5,5])            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: theta_2Slider
        function theta_2SliderValueChanging(app, event)
            transformation(app, app.theta_1Slider.Value, app.theta_2Slider.Value, app.l_1Slider.Value)
            
        end

        % Value changing function: theta_1Slider
        function theta_1SliderValueChanging(app, event)
            transformation(app, app.theta_1Slider.Value, app.theta_2Slider.Value, app.l_1Slider.Value)
            
        end

        % Value changing function: l_1Slider
        function l_1SliderValueChanging(app, event)
            transformation(app, app.theta_1Slider.Value, app.theta_2Slider.Value, app.l_1Slider.Value)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IndipendentvariablesPanel = uipanel(app.UIFigure);
            app.IndipendentvariablesPanel.Title = 'Indipendent variables';
            app.theta_1SliderLabel = uilabel(app.IndipendentvariablesPanel);
            app.theta_1SliderLabel.Text = 'theta_1';
            app.theta_1Slider = uislider(app.IndipendentvariablesPanel);
            app.theta_1Slider.Limits = [0 6.28318530717959];
            app.theta_1Slider.ValueChangingFcn = createCallbackFcn(app, @theta_1SliderValueChanging, true);
            app.theta_2SliderLabel = uilabel(app.IndipendentvariablesPanel);
            app.theta_2SliderLabel.Text = 'theta_2';
            app.theta_2Slider = uislider(app.IndipendentvariablesPanel);
            app.theta_2Slider.Limits = [0 6.28318530717959];
            app.theta_2Slider.ValueChangingFcn = createCallbackFcn(app, @theta_2SliderValueChanging, true);
            app.l_1SliderLabel = uilabel(app.IndipendentvariablesPanel);
            app.l_1SliderLabel.Text = 'l_1';
            app.l_1Slider = uislider(app.IndipendentvariablesPanel);
            app.l_1Slider.Limits = [0 3];
            app.l_1Slider.ValueChangingFcn = createCallbackFcn(app, @l_1SliderValueChanging, true);
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end