classdef lab_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CanvasoriginPanel        matlab.ui.container.Panel
        ZEditField               matlab.ui.control.NumericEditField
        ZEditFieldLabel          matlab.ui.control.Label
        YEditField               matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        XEditField               matlab.ui.control.NumericEditField
        XEditFieldLabel          matlab.ui.control.Label
        SpatulainclinationPanel  matlab.ui.container.Panel
        thetadegSlider           matlab.ui.control.Slider
        thetadegSliderLabel      matlab.ui.control.Label
        RunButton                matlab.ui.control.Button
        ManipulatorviewPanel     matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        CanvasrotationxyzPanel   matlab.ui.container.Panel
        alpha_zradSlider         matlab.ui.control.Slider
        l_1Label                 matlab.ui.control.Label
        alpha_yradSlider         matlab.ui.control.Slider
        theta_2Label             matlab.ui.control.Label
        alpha_xradSlider         matlab.ui.control.Slider
        theta_1Label             matlab.ui.control.Label
    end

    properties (Access = private)
        points_1
        x
        y
        z
    end
    
    methods (Access = private)
        function run(app)
            canvas_setup(app)
            q_red = quiver3(app.UIAxes,NaN, NaN, NaN, NaN, NaN, NaN, 'r', 'LineWidth', 2);
            q_green = quiver3(app.UIAxes,NaN, NaN, NaN, NaN, NaN, NaN, 'g', 'LineWidth', 2);
            q_blue = quiver3(app.UIAxes,NaN, NaN, NaN, NaN, NaN, NaN, 'b', 'LineWidth', 2);
            
            % Loop through the points and update the quiver plot every 0.5 seconds
            for i = 1:3:length(app.points_1)
                % Calculate the differences for each quiver
                u_red = app.x(1, i) - app.points_1(1, i);
                v_red = app.x(2, i) - app.points_1(2, i);
                w_red = app.x(3, i) - app.points_1(3, i);
                
                u_green = app.y(1, i) - app.points_1(1, i);
                v_green = app.y(2, i) - app.points_1(2, i);
                w_green = app.y(3, i) - app.points_1(3, i);
                
                u_blue = app.z(1, i) - app.points_1(1, i);
                v_blue = app.z(2, i) - app.points_1(2, i);
                w_blue = app.z(3, i) - app.points_1(3, i);
       
                set(q_red, 'XData', app.points_1(1, i), 'YData', app.points_1(2, i), 'ZData', app.points_1(3, i), ...
                            'UData', u_red, 'VData', v_red, 'WData', w_red);
                set(q_green, 'XData', app.points_1(1, i), 'YData', app.points_1(2, i), 'ZData', app.points_1(3, i), ...
                              'UData', u_green, 'VData', v_green, 'WData', w_green);
                set(q_blue, 'XData', app.points_1(1, i), 'YData', app.points_1(2, i), 'ZData', app.points_1(3, i), ...
                             'UData', u_blue, 'VData', v_blue, 'WData', w_blue);
                pause(0.1);
            end
            
            quiver3(app.UIAxes,app.points_1(1,1:3:end), app.points_1(2,1:3:end),app.points_1(3,1:3:end), app.x(1,1:3:end)-app.points_1(1,1:3:end), app.x(2,1:3:end)-app.points_1(2,1:3:end), app.x(3,1:3:end)-app.points_1(3,1:3:end), 'r', 'LineWidth', 2)
            quiver3(app.UIAxes,app.points_1(1,1:3:end), app.points_1(2,1:3:end),app.points_1(3,1:3:end), app.y(1,1:3:end)-app.points_1(1,1:3:end), app.y(2,1:3:end)-app.points_1(2,1:3:end), app.y(3,1:3:end)-app.points_1(3,1:3:end), 'g', 'LineWidth', 2)
            quiver3(app.UIAxes,app.points_1(1,1:3:end), app.points_1(2,1:3:end),app.points_1(3,1:3:end), app.z(1,1:3:end)-app.points_1(1,1:3:end), app.z(2,1:3:end)-app.points_1(2,1:3:end), app.z(3,1:3:end)-app.points_1(3,1:3:end), 'b', 'LineWidth', 2)


        end
        
        function canvas_setup(app)
            origin_c =[app.XEditField.Value ...
                app.YEditField.Value app.ZEditField.Value 1]'; % canvas origin
            
            x_0 = [1 0 0 1]';
            y_0 = [0 1 0 1]';
            z_0 = [0 0 1 1]';
            
            alpha_1 = app.alpha_xradSlider.Value;
            alpha_2 = app.alpha_yradSlider.Value;
            alpha_3 = app.alpha_zradSlider.Value;
           
            H_10 = [...
                    1 0 0 origin_c(1);...
                    0 1 0 origin_c(2);...
                    0 0 1 origin_c(3);...
                    0 0 0 1;...
                    ];

            H_10 = H_10*[...
                    1 0 0 0;...
                    0 cos(alpha_1) -sin(alpha_1) 0;...
                    0 sin(alpha_1) cos(alpha_1) 0;...
                    0 0 0 1];
            H_10 = H_10*[...
                    cos(alpha_2) 0 sin(alpha_2) 0;...
                    0 1 0 0;...
                    -sin(alpha_2) 0 cos(alpha_2) 0;...
                    0 0 0 1;...
                    ];
            H_10 = H_10*[...
                    cos(alpha_3) -sin(alpha_3) 0 0;...
                    sin(alpha_3) cos(alpha_3) 0 0; ...
                    0 0 1 0;...
                    0 0 0 1;...
                    ];

            R_43 = [...
                    1 0 0 0; 
                    0 cos(app.thetadegSlider.Value*pi/180) -sin(app.thetadegSlider.Value*pi/180) 0;...
                    0 sin(app.thetadegSlider.Value*pi/180) cos(app.thetadegSlider.Value*pi/180) 0;...
                    0 0 0 1];

            hold(app.UIAxes, 'off')
            plot3(app.UIAxes,[0,0],[0,0],[0,0])
            hold(app.UIAxes, 'on')
            grid(app.UIAxes, 'on');
            title(app.UIAxes, '');
            plotReferenceSystem(app.UIAxes, [0, 0, 0], [1,0,0], [0,1,0], [0,0,1]);


            P_0 = [0, 0, 0, 1]';
            [P_1, xyz_1] = transformReferenceSystem(H_10);
            plotReferenceSystem(app.UIAxes, P_1, xyz_1(:,1), xyz_1(:,2), xyz_1(:,3));
            
            
            q = [0 0 0 1]';
            w = [10 0 0 1]';
            e = [10 10 0 1]';
            r = [0 10 0 1]';
            t = [0 0 0 1]';
            canvas_plane = [ H_10*q H_10*w H_10*e H_10*r H_10*t];
            
            h = fill3(app.UIAxes,canvas_plane(1,:), canvas_plane(2,:), canvas_plane(3,:), 'b'); % plot in light blue
            h.FaceAlpha = 0.1; % canvas transparency to 0.1
            axis(app.UIAxes,'equal')

            tx = [8.147236863931790,9.057919370756192,...
                1.269868162935061,9.133758561390193,6.323592462254095];
            ty = [0.975404049994095,2.784982188670484,...
                5.468815192049839,9.575068354342976,9.648885351992766];
            xy = [tx; ty];
            fnplt(cscvn(xy));
            [points, t]= fnplt(cscvn(xy));
            

            app.points_1 = zeros(4,length(points));
            app.points_1(4,:)=1;
            
            app.x = zeros(4,length(points));
            app.x(4,:)=1;
            app.x = zeros(4,length(points));
            
            app.y(4,:)=1;
            app.y = zeros(4,length(points));
            app.y(4,:)=1;
            
            app.z(4,:)=1;
            app.z = zeros(4,length(points));
            app.z(4,:)=1;
            
            for i = 1:length(points)-1
                theta_1 = atan2((points(2,i) - points(2,i+1)), (points(1,i) - points(1,i+1))) + pi/2;
                H_21 = [...
                        1 0 0 points(1,i);...
                        0 1 0 points(2,i);...
                        0 0 1 0;...
                        0 0 0 1];
                H_32 = [...
                    cos(theta_1) -sin(theta_1) 0 0;...
                    sin(theta_1) cos(theta_1) 0 0; ...
                    0 0 1 0;...
                    0 0 0 1];
            
                app.points_1(:,i) = H_10*H_21*[0 0 0 1]';
                app.x(:,i) = H_10*H_21*H_32*R_43*[1 0 0 1]';
                app.y(:,i) = H_10*H_21*H_32*R_43*[0 1 0 1]';
                app.z(:,i) = H_10*H_21*H_32*R_43*[0 0 1 1]';
            end
            plot3(app.UIAxes, app.points_1(1,1:end-1), app.points_1(2,1:end-1), app.points_1(3,1:end-1) ,'-r','LineWidth', 2)

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
            addpath('./robotics_library');
            canvas_setup(app)
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            run(app)
        end

        % Value changing function: alpha_xradSlider
        function alpha_xradSliderValueChanging(app, event)
            canvas_setup(app)
            
        end

        % Value changing function: alpha_yradSlider
        function alpha_yradSliderValueChanging(app, event)
            canvas_setup(app)
            
        end

        % Value changing function: alpha_zradSlider
        function alpha_zradSliderValueChanging(app, event)
            canvas_setup(app)
            
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            canvas_setup(app)
            
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            canvas_setup(app)
            
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            canvas_setup(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CanvasrotationxyzPanel = uipanel(app.UIFigure);
            app.CanvasrotationxyzPanel.Title = 'Canvas rotation (x-y''-z'')';
            app.theta_1Label = uilabel(app.CanvasrotationxyzPanel);
            app.theta_1Label.Interpreter = 'latex';
            app.theta_1Label.Text = '$\alpha_x$ [rad]';
            app.alpha_xradSlider = uislider(app.CanvasrotationxyzPanel);
            app.alpha_xradSlider.Limits = [0 3];
            app.alpha_xradSlider.ValueChangingFcn = createCallbackFcn(app, @alpha_xradSliderValueChanging, true);
            app.alpha_xradSlider.Value = 0.5;
            app.theta_2Label = uilabel(app.CanvasrotationxyzPanel);
            app.theta_2Label.Interpreter = 'latex';
            app.theta_2Label.Text = '$\alpha_y$ [rad]';
            app.alpha_yradSlider = uislider(app.CanvasrotationxyzPanel);
            app.alpha_yradSlider.Limits = [0 3];
            app.alpha_yradSlider.ValueChangingFcn = createCallbackFcn(app, @alpha_yradSliderValueChanging, true);
            app.alpha_yradSlider.Value = 1;
            app.l_1Label = uilabel(app.CanvasrotationxyzPanel);
            app.l_1Label.Interpreter = 'latex';
            app.l_1Label.Text = '$\alpha_z$ [rad]';
            app.alpha_zradSlider = uislider(app.CanvasrotationxyzPanel);
            app.alpha_zradSlider.Limits = [0 3];
            app.alpha_zradSlider.ValueChangingFcn = createCallbackFcn(app, @alpha_zradSliderValueChanging, true);
            app.alpha_zradSlider.Value = 1;
            app.ManipulatorviewPanel = uipanel(app.UIFigure);
            app.ManipulatorviewPanel.Title = 'Manipulator view';
            app.UIAxes = uiaxes(app.ManipulatorviewPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SpatulainclinationPanel = uipanel(app.UIFigure);
            app.SpatulainclinationPanel.Title = 'Spatula inclination';
            app.thetadegSliderLabel = uilabel(app.SpatulainclinationPanel);
            app.thetadegSliderLabel.Interpreter = 'latex';
            app.thetadegSliderLabel.Text = '$\theta$ [deg]';
            app.thetadegSlider = uislider(app.SpatulainclinationPanel);
            app.thetadegSlider.Limits = [-40 -10];
            app.thetadegSlider.Value = -10;
            app.CanvasoriginPanel = uipanel(app.UIFigure);
            app.CanvasoriginPanel.Title = 'Canvas origin';
            app.XEditFieldLabel = uilabel(app.CanvasoriginPanel);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.CanvasoriginPanel, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField.Value = 1;
            app.YEditFieldLabel = uilabel(app.CanvasoriginPanel);
            app.YEditFieldLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.CanvasoriginPanel, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YEditField.Value = 1;
            app.ZEditFieldLabel = uilabel(app.CanvasoriginPanel);
            app.ZEditFieldLabel.Text = 'Z:';
            app.ZEditField = uieditfield(app.CanvasoriginPanel, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ZEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end