classdef lab_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UnfeaseableinversekinematicsLabel  matlab.ui.control.Label
        TestwithDKButton      matlab.ui.control.Button
        EEpositionPanel       matlab.ui.container.Panel
        ZEditField            matlab.ui.control.NumericEditField
        ZEditFieldLabel       matlab.ui.control.Label
        YEditField            matlab.ui.control.NumericEditField
        YEditFieldLabel       matlab.ui.control.Label
        XEditField            matlab.ui.control.NumericEditField
        XEditFieldLabel       matlab.ui.control.Label
        EEorientationwrtgroundplanePanel  matlab.ui.container.Panel
        thetadegSlider        matlab.ui.control.Slider
        thetadegSliderLabel   matlab.ui.control.Label
        ManipulatorviewPanel  matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
    end

    properties (Access = private)
        theta_1
        theta_2
        theta_3
    end
    
    methods (Access = private)
        function transformation(app)
            origin = [0 0 0 1]';

            l_5 = 2;
            l_4 = 2;
            l_3 = 2;
            l_2 = 3;
            l_1 = 1.5;

            P = [app.XEditField.Value ...
                app.YEditField.Value app.ZEditField.Value 1]';
            orientation = app.thetadegSlider.Value*pi/180;

            hold(app.UIAxes, 'off')
            plot3(app.UIAxes,[0,0],[0,0],[0,0])
            hold(app.UIAxes, 'on')
            grid(app.UIAxes, 'on');
            title(app.UIAxes, '');
            plotReferenceSystem(app.UIAxes, [0, 0, 0], [1,0,0], [0,1,0], [0,0,1]);
            plot3(app.UIAxes, P(1), P(2), P(3) ,'og','LineWidth', 7)
            
            %% H-T for the EE from the pose and orientation
            app.theta_1 = atan2(P(2), P(1));
  
            H_50 = [...
                    cos(app.theta_1 ) -sin(app.theta_1 ) 0 P(1);...
                    sin(app.theta_1 ) cos(app.theta_1 ) 0 P(2); ...
                    0 0 1 P(3);...
                    0 0 0 1];
            H_50 = H_50 * [...
                    1 0 0 0;...
                    0 cos(pi/2) -sin(pi/2) 0;...
                    0 sin(pi/2) cos(pi/2) 0;...
                    0 0 0 1];
            H_50 = H_50 * [...
                    cos(orientation) -sin(orientation) 0 0;...
                    sin(orientation) cos(orientation) 0 0; ...
                    0 0 1 0;...
                    0 0 0 1];
            
            [P_5, xyz_5] = transformReferenceSystem(H_50);
            
            plotReferenceSystem(app.UIAxes, P_5, xyz_5(:,1), xyz_5(:,2), xyz_5(:,3));

            %% D-H rappresentation of the first link
            [DH_10, P_1] = transformation_using_DH(app.UIAxes, eye(4,4), origin, 0, 0, l_1, 0);
            [DH_12, P_2] = transformation_using_DH(app.UIAxes, DH_10, P_1, l_2, pi/2, 0, app.theta_1);
            

            %% D-H rappresentation of link 3 
            P_4 = P + l_5*(P-xyz_5(:,1));
            
            plotLink(app.UIAxes, P, P_4);

            %% Cosin theorem to find theta_2 and theta_3
            H_v = [...
                    cos(-app.theta_1) -sin(-app.theta_1 ) 0 0;...
                    sin(-app.theta_1 ) cos(-app.theta_1 ) 0 0; ...
                    0 0 1 0;...
                    0 0 0 1];
            v = H_v*(P_4 - P_2); % Rotatation of the vector of theta_1 to use only the cosine theorem
            
            try
                app.theta_3 = acos((v(1)^2 + v(3)^2 - l_3^2 - l_4^2)/(2*l_3*l_4));
                app.theta_2 = atan2(v(3),v(1)) - atan2(l_4*sin(app.theta_3),l_3+l_4*cos(app.theta_3));
                
                [DH_30, P_3] = transformation_using_DH(app.UIAxes, DH_12, P_2, l_3, 0, 0, app.theta_2);
                
                [DH_40, P_4] = transformation_using_DH(app.UIAxes, DH_30, P_3, l_4, 0, 0, app.theta_3);
                
                app.UnfeaseableinversekinematicsLabel.Visible = "off";
                app.TestwithDKButton.Enable="on";
            catch ME 
                disp("Unfeaseable inverse kinematics")
                app.UnfeaseableinversekinematicsLabel.Visible = "on";
                app.TestwithDKButton.Enable="off";

            end
            axis(app.UIAxes,'equal')
        end


        function test_with_DK(app)
            hold(app.UIAxes, 'off')
            plot3(app.UIAxes,[0,0],[0,0],[0,0])
            hold(app.UIAxes, 'on')
            grid(app.UIAxes, 'on');
            plotReferenceSystem(app.UIAxes, [0, 0, 0], [1,0,0], [0,1,0], [0,0,1]);

            l_5 = 2;
            l_4 = 2;
            l_3 = 2;
            l_2 = 3;
            l_1 = 1.5;
            
            a = [0 l_2 l_3 l_4 l_5];
            alpha = [0 pi/2 0 0 0];
            d = [l_1 0 0 0 0];
            
            P = [app.XEditField.Value ...
                app.YEditField.Value app.ZEditField.Value 1]';
            orientation = app.thetadegSlider.Value*pi/180;

            phi = [0 app.theta_1 app.theta_2 app.theta_3 -app.theta_2-app.theta_3+orientation];
            DH_0 = eye(4,4);
            P_0 = [0 0 0 1]';
            rotationAxis = {'', '\theta_1', '\theta_2', '\theta_3', '\theta_4'};
            transformation_using_DH(app.UIAxes, DH_0, P_0, a, alpha, d, phi, 'm', rotationAxis);
            axis(app.UIAxes,'equal')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
            addpath('./robotics_library');
            transformation(app)
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            transformation(app)
            
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            transformation(app)
            
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            transformation(app)
            
        end

        % Button pushed function: TestwithDKButton
        function TestwithDKButtonPushed(app, event)
            test_with_DK(app)
        end

        % Value changing function: thetadegSlider
        function thetadegSliderValueChanging(app, event)
            transformation(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ManipulatorviewPanel = uipanel(app.UIFigure);
            app.ManipulatorviewPanel.Title = 'Manipulator view';
            app.UIAxes = uiaxes(app.ManipulatorviewPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EEorientationwrtgroundplanePanel = uipanel(app.UIFigure);
            app.EEorientationwrtgroundplanePanel.Title = 'EE orientation (wrt. ground plane)';
            app.thetadegSliderLabel = uilabel(app.EEorientationwrtgroundplanePanel);
            app.thetadegSliderLabel.Interpreter = 'latex';
            app.thetadegSliderLabel.Text = '$\theta$ [deg]';
            app.thetadegSlider = uislider(app.EEorientationwrtgroundplanePanel);
            app.thetadegSlider.Limits = [-90 90];
            app.thetadegSlider.ValueChangingFcn = createCallbackFcn(app, @thetadegSliderValueChanging, true);
            app.EEpositionPanel = uipanel(app.UIFigure);
            app.EEpositionPanel.Title = 'EE position';
            app.XEditFieldLabel = uilabel(app.EEpositionPanel);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.EEpositionPanel, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField.Value = 6;
            app.YEditFieldLabel = uilabel(app.EEpositionPanel);
            app.YEditFieldLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.EEpositionPanel, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YEditField.Value = 3;
            app.ZEditFieldLabel = uilabel(app.EEpositionPanel);
            app.ZEditFieldLabel.Text = 'Z:';
            app.ZEditField = uieditfield(app.EEpositionPanel, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ZEditField.Value = 3;
            app.TestwithDKButton = uibutton(app.UIFigure, 'push');
            app.TestwithDKButton.ButtonPushedFcn = createCallbackFcn(app, @TestwithDKButtonPushed, true);
            app.TestwithDKButton.Text = 'Test with DK';
            app.UnfeaseableinversekinematicsLabel = uilabel(app.UIFigure);
            app.UnfeaseableinversekinematicsLabel.Visible = 'off';
            app.UnfeaseableinversekinematicsLabel.Text = 'Unfeaseable inverse kinematics!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end