classdef lab_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SecondsolutionCheckBox  matlab.ui.control.CheckBox
        UnfeaseableinversekinematicsLabel  matlab.ui.control.Label
        TestwithDKButton        matlab.ui.control.Button
        EEpositionPanel         matlab.ui.container.Panel
        ZEditField              matlab.ui.control.NumericEditField
        ZEditFieldLabel         matlab.ui.control.Label
        YEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.NumericEditField
        XEditFieldLabel         matlab.ui.control.Label
        EEorientationwrtworldframePanel  matlab.ui.container.Panel
        theta_zdegSlider        matlab.ui.control.Slider
        theta_zdegSliderLabel   matlab.ui.control.Label
        theta_ydegSlider        matlab.ui.control.Slider
        theta_ydegSliderLabel   matlab.ui.control.Label
        theta_xdegSlider        matlab.ui.control.Slider
        theta_xdegSliderLabel   matlab.ui.control.Label
        ManipulatorviewPanel    matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end

    properties (Access = private)
        theta_1
        theta_2
        theta_3
        theta_4
        theta_5
        theta_6
        sign
    end
    
    methods (Access = private)
        function transformation(app)
            origin = [0 0 0 1]';

            l_4 = 2;
            l_3 = 2;
            l_2 = 3;
            l_1 = 1.5;
            d_6 = 1.5;


            P = [app.XEditField.Value ...
                app.YEditField.Value app.ZEditField.Value 1]';
            orientation_x = app.theta_xdegSlider.Value*pi/180;
            orientation_y = app.theta_ydegSlider.Value*pi/180;
            orientation_z = app.theta_zdegSlider.Value*pi/180;

            %% H-T for the EE from the pose and orientation

            H_60 = [...
                    cos(orientation_z) -sin(orientation_z) 0 P(1);...
                    sin(orientation_z) cos(orientation_z) 0 P(2); ...
                    0 0 1 P(3);...
                    0 0 0 1;...
                    ];
            H_60 = H_60*[...
                    cos(orientation_y) 0 sin(orientation_y) 0;...
                    0 1 0 0;...
                    -sin(orientation_y) 0 cos(orientation_y) 0;...
                    0 0 0 1;...
                    ];
            H_60 = H_60*[...
                    1 0 0 0;...
                    0 cos(orientation_x) -sin(orientation_x) 0;...
                    0 sin(orientation_x) cos(orientation_x) 0;...
                    0 0 0 1];

            hold(app.UIAxes, 'off')
            plot3(app.UIAxes,[0,0],[0,0],[0,0])
            hold(app.UIAxes, 'on')
            grid(app.UIAxes, 'on');
            title(app.UIAxes, '');
            plotReferenceSystem(app.UIAxes, [0, 0, 0], [1,0,0], [0,1,0], [0,0,1]);
            plot3(app.UIAxes, P(1), P(2), P(3) ,'og','LineWidth', 7)
           
            [P_6, xyz_6] = transformReferenceSystem(H_60);
            plotReferenceSystem(app.UIAxes, P_6, xyz_6(:,1), xyz_6(:,2), xyz_6(:,3));
            
            %% W common point
            
            W = P - d_6*H_60(:,3);

            app.theta_1 = atan2(W(2), W(1));
            %% D-H rappresentation of the first link
            [DH_10, P_1] = transformation_using_DH(app.UIAxes, eye(4,4), origin, 0, 0, l_1, 0);
            [DH_12, P_2] = transformation_using_DH(app.UIAxes, DH_10, P_1, l_2, pi/2, 0, app.theta_1);

            %% Cosin theorem to find theta_2 and theta_3
            H_v = [...
                    cos(-app.theta_1) -sin(-app.theta_1 ) 0 0;...
                    sin(-app.theta_1 ) cos(-app.theta_1 ) 0 0; ...
                    0 0 1 0;...
                    0 0 0 1];
            v = H_v* (W - P_2); % Rotatation of the vector of theta_1 to use only the cosine theorem

            try
                app.theta_3 = acos((v(1)^2 + v(3)^2 - l_3^2 - l_4^2)/(2*l_3*l_4));
                app.theta_2 = atan2(v(3),v(1)) - atan2(l_4*sin(app.theta_3),l_3+l_4*cos(app.theta_3));

                [DH_30, P_3] = transformation_using_DH(app.UIAxes, DH_12, P_2, l_3, 0, 0, app.theta_2);
    
                [DH_30_, P_3_] = transformation_using_DH(app.UIAxes, DH_30, P_3, 0, pi/2, 0, app.theta_3 + pi/2);
    
                R_60 = [...
                        cos(orientation_z) -sin(orientation_z) 0;...
                        sin(orientation_z) cos(orientation_z) 0; ...
                        0 0 1;...
                        ];
                R_60 = R_60*[...
                        cos(orientation_y) 0 sin(orientation_y);...
                        0 1 0;...
                        -sin(orientation_y) 0 cos(orientation_y);...
                        ];
                R_60 = R_60*[...
                        1 0 0;...
                        0 cos(orientation_x) -sin(orientation_x);...
                        0 sin(orientation_x) cos(orientation_x);...
                        ];
                R_30 = [...
                        cos(app.theta_1) -sin(app.theta_1) 0;...
                        sin(app.theta_1) cos(app.theta_1) 0;...
                        0 0 1;...
                        ];
                R_30 = R_30*[...
                        1 0 0;...
                        0 cos(pi/2) -sin(pi/2);...
                        0 sin(pi/2) cos(pi/2);...
                        ];
                R_30 = R_30*[...
                        cos(app.theta_2) -sin(app.theta_2) 0;...
                        sin(app.theta_2) cos(app.theta_2) 0;...
                        0 0 1;...
                        ];
                R_30 = R_30*[...
                        cos(app.theta_3 + pi/2) -sin(app.theta_3 + pi/2) 0;...
                        sin(app.theta_3 + pi/2) cos(app.theta_3 + pi/2) 0;...
                        0 0 1;...
                        ];
                R_30 = R_30*[...
                        1 0 0;...
                        0 cos(pi/2) -sin(pi/2);...
                        0 sin(pi/2) cos(pi/2);...
                        ];
                R_63 = R_30'*R_60;
                app.theta_4 = atan2(app.sign*R_63(2,3),app.sign*R_63(1,3));
                app.theta_5 = atan2(app.sign*sqrt(R_63(1,3)^2 + R_63(2,3)^2),R_63(3,3));
                app.theta_6 = atan2(app.sign*R_63(3,2), -app.sign*R_63(3,1));

                [DH_40, P_4] = transformation_using_DH(app.UIAxes, DH_30_, P_3_, 0, -pi/2, l_4, app.theta_4);
                [DH_40_, P_4_] = transformation_using_DH(app.UIAxes, DH_40, P_4, 0, pi/2, 0, app.theta_5);
                [DH_60, P_6] = transformation_using_DH(app.UIAxes, DH_40_, P_4_, 0, 0, d_6, app.theta_6);
 
                plot3(app.UIAxes, P(1), P(2), P(3) ,'og','LineWidth', 7)
                app.UnfeaseableinversekinematicsLabel.Visible = "off";
                app.TestwithDKButton.Enable="on";
            catch ME 
                disp("Unfeaseable inverse kinematics")
                app.UnfeaseableinversekinematicsLabel.Visible = "on";
                app.TestwithDKButton.Enable="off";

            end
            axis(app.UIAxes,'equal')
        end


        function test_with_DK(app)
            hold(app.UIAxes, 'off')
            plot3(app.UIAxes,[0,0],[0,0],[0,0])
            hold(app.UIAxes, 'on')
            grid(app.UIAxes, 'on');
            title(app.UIAxes, '');
            plotReferenceSystem(app.UIAxes, [0, 0, 0], [1,0,0], [0,1,0], [0,0,1]);

            l_4 = 2;
            l_3 = 2;
            l_2 = 3;
            l_1 = 1.5;
            d_6 = 1.5;

            a = [0 l_2 l_3 0 0 0 0];
            alpha = [0 pi/2 0 pi/2 -pi/2 pi/2 0];
            d = [l_1 0 0 0 l_4 0 d_6];
            
            phi = [0 app.theta_1 app.theta_2 pi/2 + app.theta_3 app.theta_4 app.theta_5 app.theta_6];

            DH_0 = eye(4,4);
            P_0 = [0 0 0 1]';
            rotationAxis = {'', '\theta_1', '\theta_2', '\theta_3', '\theta_4', '\theta_5', '\theta_6'};
            transformation_using_DH(app.UIAxes, DH_0, P_0, a, alpha, d, phi, 'm', rotationAxis);
            axis(app.UIAxes,'equal')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
            addpath('./robotics_library');
            app.sign = 1;
            transformation(app)
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            transformation(app)
            
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            transformation(app)
            
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            transformation(app)
            
        end

        % Button pushed function: TestwithDKButton
        function TestwithDKButtonPushed(app, event)
            test_with_DK(app)
        end

        % Value changing function: theta_xdegSlider
        function theta_xdegSliderValueChanging(app, event)
            transformation(app)
      
        end

        % Value changing function: theta_ydegSlider
        function theta_ydegSliderValueChanging(app, event)
            transformation(app)

        end

        % Value changed function: theta_zdegSlider
        function theta_zdegSliderValueChanged(app, event)
            transformation(app)  
            
        end

        % Value changed function: SecondsolutionCheckBox
        function SecondsolutionCheckBoxValueChanged(app, event)
            if app.SecondsolutionCheckBox.Value == 1
                app.sign = -1;
            else
                app.sign = 1;
            end
            transformation(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ManipulatorviewPanel = uipanel(app.UIFigure);
            app.ManipulatorviewPanel.Title = 'Manipulator view';
            app.UIAxes = uiaxes(app.ManipulatorviewPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EEorientationwrtworldframePanel = uipanel(app.UIFigure);
            app.EEorientationwrtworldframePanel.Title = 'EE orientation (wrt. world frame)';
            app.theta_xdegSliderLabel = uilabel(app.EEorientationwrtworldframePanel);
            app.theta_xdegSliderLabel.Interpreter = 'latex';
            app.theta_xdegSliderLabel.Text = '$\theta_x$ [deg]';
            app.theta_xdegSlider = uislider(app.EEorientationwrtworldframePanel);
            app.theta_xdegSlider.Limits = [-90 90];
            app.theta_xdegSlider.ValueChangingFcn = createCallbackFcn(app, @theta_xdegSliderValueChanging, true);
            app.theta_ydegSliderLabel = uilabel(app.EEorientationwrtworldframePanel);
            app.theta_ydegSliderLabel.Interpreter = 'latex';
            app.theta_ydegSliderLabel.Text = '$\theta_y$ [deg]';
            app.theta_ydegSlider = uislider(app.EEorientationwrtworldframePanel);
            app.theta_ydegSlider.Limits = [-90 90];
            app.theta_ydegSlider.ValueChangingFcn = createCallbackFcn(app, @theta_ydegSliderValueChanging, true);
            app.theta_zdegSliderLabel = uilabel(app.EEorientationwrtworldframePanel);
            app.theta_zdegSliderLabel.Interpreter = 'latex';
            app.theta_zdegSliderLabel.Text = '$\theta_z$ [deg]';
            app.theta_zdegSlider = uislider(app.EEorientationwrtworldframePanel);
            app.theta_zdegSlider.Limits = [-90 90];
            app.theta_zdegSlider.ValueChangedFcn = createCallbackFcn(app, @theta_zdegSliderValueChanged, true);
            app.EEpositionPanel = uipanel(app.UIFigure);
            app.EEpositionPanel.Title = 'EE position';
            app.XEditFieldLabel = uilabel(app.EEpositionPanel);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.EEpositionPanel, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField.Value = 6;
            app.YEditFieldLabel = uilabel(app.EEpositionPanel);
            app.YEditFieldLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.EEpositionPanel, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YEditField.Value = 3;
            app.ZEditFieldLabel = uilabel(app.EEpositionPanel);
            app.ZEditFieldLabel.Text = 'Z:';
            app.ZEditField = uieditfield(app.EEpositionPanel, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ZEditField.Value = 3;
            app.TestwithDKButton = uibutton(app.UIFigure, 'push');
            app.TestwithDKButton.ButtonPushedFcn = createCallbackFcn(app, @TestwithDKButtonPushed, true);
            app.TestwithDKButton.Text = 'Test with DK';
            app.UnfeaseableinversekinematicsLabel = uilabel(app.UIFigure);
            app.UnfeaseableinversekinematicsLabel.Visible = 'off';
            app.UnfeaseableinversekinematicsLabel.Text = 'Unfeaseable inverse kinematics!';
            app.SecondsolutionCheckBox = uicheckbox(app.UIFigure);
            app.SecondsolutionCheckBox.ValueChangedFcn = createCallbackFcn(app, @SecondsolutionCheckBoxValueChanged, true);
            app.SecondsolutionCheckBox.Text = 'Second solution';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end