classdef lab_6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        DKTab                      matlab.ui.container.Tab
        PointisoutsideoftheworkspaceLabel_2  matlab.ui.control.Label
        IndipendentvariablesPanel  matlab.ui.container.Panel
        q_1degKnob                 matlab.ui.control.Knob
        q_1degLabel                matlab.ui.control.Label
        q_2degKnob                 matlab.ui.control.Knob
        q_2degLabel                matlab.ui.control.Label
        CablerobotviewPanel        matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        IKTab                      matlab.ui.container.Tab
        PointisoutsideoftheworkspaceLabel  matlab.ui.control.Label
        EEpositionPanel            matlab.ui.container.Panel
        YmEditField                matlab.ui.control.NumericEditField
        YmEditFieldLabel           matlab.ui.control.Label
        XmEditField                matlab.ui.control.NumericEditField
        XmEditFieldLabel           matlab.ui.control.Label
        CablerobotviewPanel_2      matlab.ui.container.Panel
        UIAxes_2                   matlab.ui.control.UIAxes
        ManipulabilityTab          matlab.ui.container.Tab
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
    end

    properties (Access = private)
        P
        q
    end
    
    methods (Access = private)
        function direct_k(app)
            l = 10;
            h = 6;
            r_1 = 0.5;
            r_2 = 0.5;
            P_1 = [-1 -r_1]';
            P_1_c = [-1 0]';
            P_2 = [l+1, -r_2]';
            P_2_c = [l+1, 0]';
            
            A = [0 0]';
            B = [l 0]';
            C = [l -h]';
            D = [0 -h]';
            k = [A B C D];

            hold(app.UIAxes, 'off')
            plot(app.UIAxes,[0,0],[0,0])
            hold(app.UIAxes, 'on')
            grid(app.UIAxes, 'on');
            title(app.UIAxes, '');

            canv = fill(app.UIAxes,k(1,:), k(2,:), 'b');
            canv.FaceAlpha = 0.1;

            origin = [0 0]';
            x_0 = [1 0 ]';
            y_0 = [0 1]';
            
            quiver(app.UIAxes,origin(1), origin(2), x_0(1)-origin(1), x_0(2)-origin(2), 'r', 'LineWidth', 3)
            quiver(app.UIAxes,origin(1), origin(2), y_0(1)-origin(1), y_0(2)-origin(2), 'g', 'LineWidth', 3)
            
            theta = linspace(0, 2*pi, 30);
            plot(app.UIAxes,P_1(1) + r_1 * cos(theta), P_1(2) + r_1 * sin(theta), 'b-', 'LineWidth', 1);
            plot(app.UIAxes,P_2(1) + r_2 * cos(theta), P_2(2) + r_2 * sin(theta), 'b-', 'LineWidth', 1);
            
            k_1 = norm(origin' - P_1_c');
            k_2 = norm(B' - P_2_c') + l;
            
            app.q = [app.q_1degKnob.Value app.q_2degKnob.Value]*pi/180;
            l_1 = r_1 * app.q(1);
            l_2 = r_2 * app.q(2) + l;
            
            text(app.UIAxes,P_1(1)-0.5, P_1(2)+r_1*2, num2str(app.q(1)*180/pi,'%.f°'), 'FontSize', 12, 'Color', 'red');
            text(app.UIAxes,P_2(1), P_2(2)+r_2*2, num2str(app.q(2)*180/pi,'%.f°'), 'FontSize', 12, 'Color', 'red');
            plot(app.UIAxes,[P_1_c(1)  A(1)], [P_1_c(2)  A(2)], 'k-.','LineWidth', 2)
            plot(app.UIAxes,[P_2_c(1)  B(1)], [P_2_c(2)  B(2)], 'k-.','LineWidth', 2)

            theta_1 = acos((l_1^2 + B(1)^2 - l_2^2)/(2*l_1*B(1)));
            
            app.P = [l_1*cos(-theta_1) l_1*sin(-theta_1)]';

            if app.P(1) > 0.01 && app.P(1) < l-0.01 && app.P(2) < -0.01 && app.P(2) > -h +0.01
                app.PointisoutsideoftheworkspaceLabel_2.Visible = "off";
                plot(app.UIAxes, [app.P(1)  B(1)], [app.P(2)  B(2)], 'k-.','LineWidth', 2)
                plot(app.UIAxes, [app.P(1)  A(1)], [app.P(2)  A(2)], 'k-.','LineWidth', 2)
                plot(app.UIAxes, app.P(1), app.P(2) ,'og','LineWidth', 5);
                app.XmEditField.Value = app.P(1);
                app.YmEditField.Value = app.P(2);

                %% Manipulability 
                F = [app.P(1) app.P(2); (app.P(1)-l) app.P(2)];
                G = [r_1^2*app.q(1) 0; 0 r_2*(l+r_2*app.q(2))];
                J = F\G;
                [V, D] = eig(J*J');
                
                theta = linspace(0, 2*pi, 100);
                circle = [cos(theta); sin(theta)]; 
                
                axes_lengths = sqrt(diag(D));
                scaled_circle = diag(axes_lengths) * circle;
                
                ellipsoid_points = V * scaled_circle + app.P;
                
                plot(app.UIAxes, ellipsoid_points(1, :), ellipsoid_points(2, :), 'LineWidth', 1);
            else
                disp("Point is outside of the workspace")
                app.PointisoutsideoftheworkspaceLabel_2.Visible = "on";
                app.XmEditField.Value = 0;
                app.YmEditField.Value = 0;
            end
            xlim(app.UIAxes,[-4 14])
            axis(app.UIAxes,'equal')
        end

        function inverse_k(app)
            app.P = [app.XmEditField.Value app.YmEditField.Value]';
            l = 10;
            h = 6;
            r_1 = 0.5;
            r_2 = 0.5;
            P_1 = [-1 -r_1]';
            P_1_c = [-1 0]';
            P_2 = [l+1, -r_2]';
            P_2_c = [l+1, 0]';
            
            A = [0 0]';
            B = [l 0]';
            C = [l -h]';
            D = [0 -h]';
            k = [A B C D];

            hold(app.UIAxes_2, 'off')
            plot(app.UIAxes_2,[0,0],[0,0])
            hold(app.UIAxes_2, 'on')
            grid(app.UIAxes_2, 'on');
            title(app.UIAxes_2, '');

            canv = fill(app.UIAxes_2, k(1,:), k(2,:), 'b');
            canv.FaceAlpha = 0.1;

            origin = [0 0]';
            x_0 = [1 0 ]';
            y_0 = [0 1]';
            
            quiver(app.UIAxes_2,origin(1), origin(2), x_0(1)-origin(1), x_0(2)-origin(2), 'r', 'LineWidth', 3)
            quiver(app.UIAxes_2,origin(1), origin(2), y_0(1)-origin(1), y_0(2)-origin(2), 'g', 'LineWidth', 3)
            
            theta = linspace(0, 2*pi, 30);
            plot(app.UIAxes_2, [P_1_c(1)  A(1)], [P_1_c(2)  A(2)], 'k-.','LineWidth', 2)
            plot(app.UIAxes_2, [P_2_c(1)  B(1)], [P_2_c(2)  B(2)], 'k-.','LineWidth', 2)
            plot(app.UIAxes_2, P_1(1) + r_1 * cos(theta), P_1(2) + r_1 * sin(theta), 'b-', 'LineWidth', 1);
            plot(app.UIAxes_2, P_2(1) + r_2 * cos(theta), P_2(2) + r_2 * sin(theta), 'b-', 'LineWidth', 1);
            
            l_1 = norm(A-app.P);
            l_2 = norm(B-app.P);
            
            app.q = [l_1/r_1, (l_2-l)/r_2];

            if app.P(1) > 0.01 && app.P(1) < l-0.01 && app.P(2) < -0.01 && app.P(2) > -h +0.01
                app.PointisoutsideoftheworkspaceLabel.Visible = "off";
                plot(app.UIAxes_2, [app.P(1)  B(1)], [app.P(2)  B(2)], 'k-.','LineWidth', 2)
                plot(app.UIAxes_2, [app.P(1)  A(1)], [app.P(2)  A(2)], 'k-.','LineWidth', 2)

                app.q_1degKnob.Value = app.q(1)*180/pi;
                app.q_2degKnob.Value = app.q(2)*180/pi;
                text(app.UIAxes_2,P_1(1)-0.5, P_1(2)+r_1*2, num2str(app.q(1)*180/pi,'%.f°'), 'FontSize', 12, 'Color', 'red');
                text(app.UIAxes_2,P_2(1), P_2(2)+r_2*2, num2str(app.q(2)*180/pi,'%.f°'), 'FontSize', 12, 'Color', 'red');


                %% Manipulability 
                F = [app.P(1) app.P(2); (app.P(1)-l) app.P(2)];
                G = [r_1^2*app.q(1) 0; 0 r_2*(l+r_2*app.q(2))];
                J = F\G;
                [V, D] = eig(J*J');
                
                theta = linspace(0, 2*pi, 100);
                circle = [cos(theta); sin(theta)]; 
                
                axes_lengths = sqrt(diag(D));
                scaled_circle = diag(axes_lengths) * circle;
                
                ellipsoid_points = V * scaled_circle + app.P;
                
                plot(app.UIAxes_2, ellipsoid_points(1, :), ellipsoid_points(2, :), 'LineWidth', 1);
            else
                disp("Point is outside of the workspace")
                app.PointisoutsideoftheworkspaceLabel.Visible = "on";
                app.q_1degKnob.Value = 0;
                app.q_2degKnob.Value = 0;
                text(app.UIAxes_2,P_1(1), P_1(2)+r_1*2, '', 'FontSize', 12, 'Color', 'red');
                text(app.UIAxes_2,P_2(1), P_2(2)+r_2*2, '', 'FontSize', 12, 'Color', 'red');

            end
            plot(app.UIAxes_2, app.P(1), app.P(2) ,'og','LineWidth', 5);
            xlim(app.UIAxes_2,[-4 14])
            axis(app.UIAxes_2,'equal')
        end

        function manipulability(app)

            %% Manipulability elipsoids
            l = 10;
            h = 6;
            r_1 = 0.5;
            r_2 = 0.5;
            A = [0 0]';
            B = [l 0]';
            C = [l -h]';
            D = [0 -h]';
            k = [A B C D];
            canv = fill(app.UIAxes2, k(1,:), k(2,:), 'b');
            canv.FaceAlpha = 0.1;
            hold(app.UIAxes2, 'on')
            grid(app.UIAxes2, 'on');
            title(app.UIAxes2, 'Manipulability Elipsoids');
            [X, Y] = meshgrid(linspace(0, l, 5), linspace(-h, -1, 5));
            
            for ii = 1:length(X(1,:))
                for k = 1:length(X(:,1))
                    P_ = [X(k,ii), Y(k,ii)]';
                    l_1 = norm(A-P_);
                    l_2 = norm(B-P_);
                    
                    q_ = [l_1/r_1, (l_2-l)/r_2];
                    
                    F = [P_(1) P_(2); (P_(1)-l) P_(2)];
                    G = [r_1^2*q_(1) 0; 0 r_2*(l+r_2*q_(2))];
                    J = F\G;
                    [V, D] = eig(J*J');
                    [V_f, D_f] = eig(inv((J*J')));
                    
                    theta = linspace(0, 2*pi, 100);
                    circle = [cos(theta); sin(theta)]; 
                    
                    axes_lengths = sqrt(diag(D));
                    axes_lengths_f = sqrt(diag(D_f))/2;
            
                    scaled_circle = diag(axes_lengths) * circle;
                    scaled_circle_force = diag(axes_lengths_f) * circle;
            
                    ellipsoid_points = V * scaled_circle + P_;
                    ellipsoid_points_force = V_f * scaled_circle_force + P_;
                    
                    % Plot the ellipsoid
                    plot(app.UIAxes2, ellipsoid_points(1, :), ellipsoid_points(2, :), 'b','LineWidth', 1);
                    plot(app.UIAxes2, ellipsoid_points_force(1, :), ellipsoid_points_force(2, :), 'r','LineWidth', 1);
                end
            end
            legend(app.UIAxes2, '', 'Velocity Ellipsoids', 'Force Ellipsoids');
            axis(app.UIAxes2,'equal')
            xlim(app.UIAxes2,[-4 14])
            ylim(app.UIAxes2,[-15 5])
            
            %% Manipulability volumes

            [X, Y] = meshgrid(linspace(0, l, 100), linspace(-h, -0.3, 100));
            V = zeros(100,100);

            grid(app.UIAxes3, 'on');
            title(app.UIAxes3, 'Manipulability Volumes');
            
            for ii = 1:length(X(1,:))
                for k = 1:length(X(:,1))
                    P_ = [X(k,ii), Y(k,ii)]';
                    l_1 = norm(A-P_);
                    l_2 = norm(B-P_);
                    
                    q_ = [l_1/r_1, (l_2-l)/r_2];
                    
                    F = [P_(1) P_(2); (P_(1)-l) P_(2)];
                    G = [r_1^2*q_(1) 0; 0 r_2*(l+r_2*q_(2))];
                    J = F\G;
                    V(k,ii) = det(J*J');
                   
                end
            end
            V_p =  V .^ 0.0001;
            imagesc(app.UIAxes3, [0, l], [-h, -0.3], V_p)
            axis(app.UIAxes3,'equal')
            xlim(app.UIAxes3,[-4 14])
            ylim(app.UIAxes3,[-15 5])
            set(app.UIAxes3, 'YDir', 'normal');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
            addpath('./robotics_library');
            direct_k(app);
            manipulability(app)
        end

        % Value changing function: q_2degKnob
        function q_2degKnobValueChanging(app, event)
            direct_k(app);
        end

        % Value changing function: q_1degKnob
        function q_1degKnobValueChanging(app, event)
            direct_k(app);
        end

        % Button down function: IKTab
        function IKTabButtonDown(app, event)
            inverse_k(app);
        end

        % Button down function: DKTab
        function DKTabButtonDown(app, event)
            direct_k(app);
        end

        % Value changed function: XmEditField
        function XmEditFieldValueChanged(app, event)
            inverse_k(app);
        end

        % Value changed function: YmEditField
        function YmEditFieldValueChanged(app, event)
            inverse_k(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DKTab = uitab(app.TabGroup);
            app.DKTab.Title = 'DK';
            app.DKTab.ButtonDownFcn = createCallbackFcn(app, @DKTabButtonDown, true);
            app.CablerobotviewPanel = uipanel(app.DKTab);
            app.CablerobotviewPanel.Title = 'Cable robot view';
            app.UIAxes = uiaxes(app.CablerobotviewPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.IndipendentvariablesPanel = uipanel(app.DKTab);
            app.IndipendentvariablesPanel.Title = 'Indipendent variables';
            app.q_2degLabel = uilabel(app.IndipendentvariablesPanel);
            app.q_2degLabel.Interpreter = 'latex';
            app.q_2degLabel.Text = '$q_2$ [deg] =';
            app.q_2degKnob = uiknob(app.IndipendentvariablesPanel, 'continuous');
            app.q_2degKnob.Limits = [-1000 1000];
            app.q_2degKnob.ValueChangingFcn = createCallbackFcn(app, @q_2degKnobValueChanging, true);
            app.q_2degKnob.Value = -500;
            app.q_1degLabel = uilabel(app.IndipendentvariablesPanel);
            app.q_1degLabel.Interpreter = 'latex';
            app.q_1degLabel.Text = '$q_1$  [deg] = ';
            app.q_1degKnob = uiknob(app.IndipendentvariablesPanel, 'continuous');
            app.q_1degKnob.Limits = [-1000 1000];
            app.q_1degKnob.ValueChangingFcn = createCallbackFcn(app, @q_1degKnobValueChanging, true);
            app.q_1degKnob.Value = 650;
            app.PointisoutsideoftheworkspaceLabel_2 = uilabel(app.DKTab);
            app.PointisoutsideoftheworkspaceLabel_2.Visible = 'off';
            app.PointisoutsideoftheworkspaceLabel_2.Text = 'Point is outside of the workspace';
            app.IKTab = uitab(app.TabGroup);
            app.IKTab.Title = 'IK';
            app.IKTab.ButtonDownFcn = createCallbackFcn(app, @IKTabButtonDown, true);
            app.CablerobotviewPanel_2 = uipanel(app.IKTab);
            app.CablerobotviewPanel_2.Title = 'Cable robot view';
            app.UIAxes_2 = uiaxes(app.CablerobotviewPanel_2);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.EEpositionPanel = uipanel(app.IKTab);
            app.EEpositionPanel.Title = 'EE position';
            app.XmEditFieldLabel = uilabel(app.EEpositionPanel);
            app.XmEditFieldLabel.Text = 'X [m] = ';
            app.XmEditField = uieditfield(app.EEpositionPanel, 'numeric');
            app.XmEditField.ValueChangedFcn = createCallbackFcn(app, @XmEditFieldValueChanged, true);
            app.XmEditField.Value = 6;
            app.YmEditFieldLabel = uilabel(app.EEpositionPanel);
            app.YmEditFieldLabel.Text = 'Y [m] = ';
            app.YmEditField = uieditfield(app.EEpositionPanel, 'numeric');
            app.YmEditField.ValueChangedFcn = createCallbackFcn(app, @YmEditFieldValueChanged, true);
            app.YmEditField.Value = 3;
            app.PointisoutsideoftheworkspaceLabel = uilabel(app.IKTab);
            app.PointisoutsideoftheworkspaceLabel.Visible = 'off';
            app.PointisoutsideoftheworkspaceLabel.Text = 'Point is outside of the workspace';
            app.ManipulabilityTab = uitab(app.TabGroup);
            app.ManipulabilityTab.Title = 'Manipulability';
            app.UIAxes3 = uiaxes(app.ManipulabilityTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.ManipulabilityTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab_6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end