classdef lab_7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        DirectDynamicAnalysisTab  matlab.ui.container.Tab
        Panel                     matlab.ui.container.Panel
        gms2EditField             matlab.ui.control.NumericEditField
        gLabel                    matlab.ui.control.Label
        q_20radEditField          matlab.ui.control.NumericEditField
        q_20Label                 matlab.ui.control.Label
        q_10radEditField          matlab.ui.control.NumericEditField
        q_10Label                 matlab.ui.control.Label
        t_maxsSlider              matlab.ui.control.Slider
        t_maxsLabel               matlab.ui.control.Label
        tau_2NmEditField          matlab.ui.control.EditField
        tau_2Label                matlab.ui.control.Label
        a_2mEditField             matlab.ui.control.NumericEditField
        a_2Label                  matlab.ui.control.Label
        m_2kgEditField            matlab.ui.control.NumericEditField
        m_2Label                  matlab.ui.control.Label
        a_1mEditField             matlab.ui.control.NumericEditField
        a_1Label                  matlab.ui.control.Label
        m_1kgEditField            matlab.ui.control.NumericEditField
        m_1Label                  matlab.ui.control.Label
        tau_1NmEditField          matlab.ui.control.EditField
        tau_1Label                matlab.ui.control.Label
        StartButton               matlab.ui.control.Button
        Panel_3                   matlab.ui.container.Panel
        GridLayout                matlab.ui.container.GridLayout
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes5                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function direct_dynamic_analysis(app)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
            cla(app.UIAxes5)
            t = linspace(0, app.t_maxsSlider.Value, app.t_maxsSlider.Value*30);

            theta_1_arr = zeros(1, length(t)+1);
            theta_2_arr = zeros(1, length(t)+1);
            theta_1_d_arr = zeros(1, length(t)+1);
            theta_2_d_arr = zeros(1, length(t)+1);
            theta_1_dd_arr = zeros(1, length(t));
            theta_2_dd_arr = zeros(1, length(t));

            theta_1 = app.q_10radEditField.Value;
            theta_2 = app.q_20radEditField.Value;
            theta_1_d = 0;
            theta_2_d = 0;

            theta_1_arr(1) = theta_1;
            theta_2_arr(1) = theta_2;
            theta_1_d_arr(1) = theta_1_d;
            theta_2_d_arr(1) = theta_2_d;        

            tau = [eval(app.tau_1NmEditField.Value); eval(app.tau_2NmEditField.Value)];
            delta_t = t(2)-t(1);

            a_1 = app.a_1mEditField.Value;
            a_2 = app.a_2mEditField.Value;
            m_1 = app.m_1kgEditField.Value;
            m_2 = app.m_2kgEditField.Value;
            g = app.gms2EditField.Value;

            for ii = 1:length(t)

                q_dd = lab_7_dinamic_function(tau(:,ii), theta_1, theta_2, theta_1_d, theta_2_d, a_1, a_2, m_1, m_2, g);
                theta_1_d = theta_1_d + q_dd(1) * delta_t;
                theta_2_d = theta_2_d + q_dd(2) * delta_t;
                theta_1 = theta_1 + theta_1_d * delta_t;
                theta_2 = theta_2 + theta_2_d * delta_t;

                theta_1_arr(ii+1) = theta_1;
                theta_2_arr(ii+1) = theta_2;
                theta_1_d_arr(ii+1) = theta_1_d;
                theta_2_d_arr(ii+1) = theta_2_d;
                theta_1_dd_arr(ii) = q_dd(1);
                theta_2_dd_arr(ii) = q_dd(1);
            end

            h_tau1 = plot(app.UIAxes, t(1), tau(1,1), 'r.-');
            hold(app.UIAxes, 'on')
            h_tau2 = plot(app.UIAxes, t(1), tau(2,1) , 'b.-');
            title(app.UIAxes, 'Torques')
            grid(app.UIAxes, 'on')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Torque [Nm]')
            legend(app.UIAxes, {'\tau_1','\tau_2'})
            
            h_q1 = plot(app.UIAxes2, t(1), theta_1_arr(1) , 'r.-');
            hold(app.UIAxes2, 'on')
            h_q2 = plot(app.UIAxes2, t(1), theta_1_arr(1), 'b.-');
            title(app.UIAxes2, 'Joint Angles')
            grid(app.UIAxes2, 'on')
            xlabel(app.UIAxes2, 'Time [s]')
            ylabel(app.UIAxes2, 'Position [rad]')
            legend(app.UIAxes2, {'q_1','q_2'})
            
            h_q1_d = plot(app.UIAxes3, t(1), theta_1_d_arr(1), 'r.-');
            hold(app.UIAxes3, 'on')
            h_q2_d = plot(app.UIAxes3, t(1), theta_2_d_arr(1), 'b.-');
            title(app.UIAxes3, 'Joint Speed')
            grid(app.UIAxes3, 'on')
            xlabel(app.UIAxes3, 'Time [s]')
            ylabel(app.UIAxes3, 'Position [rad]')
            legend(app.UIAxes3, {'$$\dot{q}_1$$', '$$\dot{q}_2$$'}, 'Interpreter', 'latex')
            
            h_q1_dd = plot(app.UIAxes4, t(1), theta_1_dd_arr(1), 'r.-');
            hold(app.UIAxes4, 'on')
            h_q2_dd = plot(app.UIAxes4, t(1), theta_2_dd_arr(1), 'b.-');
            title(app.UIAxes4, 'Joint Acceleration')
            grid(app.UIAxes4, 'on')
            xlabel(app.UIAxes4, 'Time [s]')
            ylabel(app.UIAxes4, 'Position [rad]')
            legend(app.UIAxes4, {'$$\ddot{q}_1$$', '$$\ddot{q}_2$$'}, 'Interpreter', 'latex')

            for ii = 1:length(t)
                set(h_tau1, 'XData', t(1:ii), 'YData', tau(1,1:ii));
                set(h_tau2, 'XData', t(1:ii), 'YData', tau(2,1:ii));

                set(h_q1, 'XData', t(1:ii), 'YData', theta_1_arr(1:ii));
                set(h_q2, 'XData', t(1:ii), 'YData', theta_2_arr(1:ii));

                set(h_q1_d, 'XData', t(1:ii), 'YData', theta_1_d_arr(1:ii));
                set(h_q2_d, 'XData', t(1:ii), 'YData', theta_2_d_arr(1:ii));

                set(h_q1_dd, 'XData', t(1:ii), 'YData', theta_1_dd_arr(1:ii));
                set(h_q2_dd, 'XData', t(1:ii), 'YData', theta_2_dd_arr(1:ii));

                x0 = 0; y0 = 0;
                x1 = a_1 * cos(theta_1_arr(ii));
                y1 = a_1 * sin(theta_1_arr(ii));
                x2 = x1 + a_2 * cos(theta_1_arr(ii) + theta_2_arr(ii));
                y2 = y1 + a_2 * sin(theta_1_arr(ii) + theta_2_arr(ii));
                cla(app.UIAxes5)
                plot(app.UIAxes5, [x0 x1], [y0 y1], 'k-o', 'LineWidth', 2, 'MarkerSize', 6)
                hold(app.UIAxes5, 'on')
                plot(app.UIAxes5, [x1 x2], [y1 y2], 'k', 'LineWidth', 2, 'MarkerSize', 6)
                axis(app.UIAxes5, 'equal')
                xlim(app.UIAxes5, [-a_1-a_2, a_1+a_2])
                ylim(app.UIAxes5, [-a_1-a_2, a_1+a_2])
                title(app.UIAxes5, '2-Link Arm Animation')
                grid(app.UIAxes5, 'on')
                drawnow
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed2(app, event)
            direct_dynamic_analysis(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DirectDynamicAnalysisTab = uitab(app.TabGroup);
            app.DirectDynamicAnalysisTab.Title = 'Direct Dynamic Analysis';
            app.UIAxes5 = uiaxes(app.DirectDynamicAnalysisTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.Panel_3 = uipanel(app.DirectDynamicAnalysisTab);
            app.GridLayout = uigridlayout(app.Panel_3);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Panel = uipanel(app.DirectDynamicAnalysisTab);
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed2, true);
            app.StartButton.Text = 'Start';
            app.tau_1Label = uilabel(app.Panel);
            app.tau_1Label.Interpreter = 'latex';
            app.tau_1Label.Text = '$$\tau_1 [Nm]=$$';
            app.tau_1NmEditField = uieditfield(app.Panel, 'text');
            app.tau_1NmEditField.Value = 'sin(2*pi*0.2*t)';
            app.m_1Label = uilabel(app.Panel);
            app.m_1Label.Interpreter = 'latex';
            app.m_1Label.Text = '$$m_1 [kg]=$$';
            app.m_1kgEditField = uieditfield(app.Panel, 'numeric');
            app.m_1kgEditField.Limits = [0 Inf];
            app.m_1kgEditField.Value = 1;
            app.a_1Label = uilabel(app.Panel);
            app.a_1Label.Interpreter = 'latex';
            app.a_1Label.Text = '$$a_1[m]=$$';
            app.a_1mEditField = uieditfield(app.Panel, 'numeric');
            app.a_1mEditField.Limits = [0 Inf];
            app.a_1mEditField.Value = 1;
            app.m_2Label = uilabel(app.Panel);
            app.m_2Label.Interpreter = 'latex';
            app.m_2Label.Text = '$$m_2 [kg] =$$';
            app.m_2kgEditField = uieditfield(app.Panel, 'numeric');
            app.m_2kgEditField.Limits = [0 Inf];
            app.m_2kgEditField.Value = 1;
            app.a_2Label = uilabel(app.Panel);
            app.a_2Label.Interpreter = 'latex';
            app.a_2Label.Text = '$$a_2[m]=$$';
            app.a_2mEditField = uieditfield(app.Panel, 'numeric');
            app.a_2mEditField.Limits = [0 Inf];
            app.a_2mEditField.Value = 1;
            app.tau_2Label = uilabel(app.Panel);
            app.tau_2Label.Interpreter = 'latex';
            app.tau_2Label.Text = '$$\tau_2 [Nm]=$$';
            app.tau_2NmEditField = uieditfield(app.Panel, 'text');
            app.tau_2NmEditField.Value = 'sin(2*pi*0.2*t - pi/4)';
            app.t_maxsLabel = uilabel(app.Panel);
            app.t_maxsLabel.Interpreter = 'latex';
            app.t_maxsLabel.Text = '$t_{max}$ [s]';
            app.t_maxsSlider = uislider(app.Panel);
            app.t_maxsSlider.Limits = [1 10];
            app.t_maxsSlider.Value = 1;
            app.q_10Label = uilabel(app.Panel);
            app.q_10Label.Interpreter = 'latex';
            app.q_10Label.Text = '$q_1 (0)$ [rad] =';
            app.q_10radEditField = uieditfield(app.Panel, 'numeric');
            app.q_20Label = uilabel(app.Panel);
            app.q_20Label.Interpreter = 'latex';
            app.q_20Label.Text = '$q_2 (0)$ [rad] =';
            app.q_20radEditField = uieditfield(app.Panel, 'numeric');
            app.gLabel = uilabel(app.Panel);
            app.gLabel.Interpreter = 'latex';
            app.gLabel.Text = '$g [m/s^2]$ =';
            app.gms2EditField = uieditfield(app.Panel, 'numeric');
            app.gms2EditField.Value = 9.81;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab_7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end