classdef lab_7_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        StepresponseTab              matlab.ui.container.Tab
        Panel                        matlab.ui.container.Panel
        RefvelocitysignalforfastresponseCheckBox  matlab.ui.control.CheckBox
        GravitycompensationCheckBox  matlab.ui.control.CheckBox
        q_10radEditField             matlab.ui.control.NumericEditField
        q_10radEditFieldLabel        matlab.ui.control.Label
        q_20radEditField             matlab.ui.control.NumericEditField
        q_10radLabel_2               matlab.ui.control.Label
        gms2EditField                matlab.ui.control.NumericEditField
        gLabel                       matlab.ui.control.Label
        K_p1Slider                   matlab.ui.control.Slider
        K_p1Slider_2Label            matlab.ui.control.Label
        K_d1Slider                   matlab.ui.control.Slider
        K_d1Slider_2Label            matlab.ui.control.Label
        K_i1Slider                   matlab.ui.control.Slider
        K_i1Slider_2Label            matlab.ui.control.Label
        K_p2Slider                   matlab.ui.control.Slider
        K_p2Slider_2Label            matlab.ui.control.Label
        K_d2Slider                   matlab.ui.control.Slider
        K_d2Slider_2Label            matlab.ui.control.Label
        K_i2Slider                   matlab.ui.control.Slider
        K_i2SliderLabel              matlab.ui.control.Label
        t_maxsSlider                 matlab.ui.control.Slider
        t_maxsLabel                  matlab.ui.control.Label
        Panel_3                      matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes5                      matlab.ui.control.UIAxes
        TrajectoryTab_2              matlab.ui.container.Tab
        Panel_5                      matlab.ui.container.Panel
        WStrajectoryCheckBox         matlab.ui.control.CheckBox
        t_maxsSlider_3               matlab.ui.control.Slider
        t_maxsSlider_3Label          matlab.ui.control.Label
        a_maxms2Slider               matlab.ui.control.Slider
        a_maxms2SliderLabel          matlab.ui.control.Label
        Panel_4                      matlab.ui.container.Panel
        GridLayout_2                 matlab.ui.container.GridLayout
        UIAxes4_2                    matlab.ui.control.UIAxes
        UIAxes2_2                    matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes5_2                    matlab.ui.control.UIAxes
        RobotICControlTab            matlab.ui.container.Tab
        Panel_6                      matlab.ui.container.Panel
        GridLayout_3                 matlab.ui.container.GridLayout
        UIAxes4_4                    matlab.ui.control.UIAxes
        UIAxes2_4                    matlab.ui.control.UIAxes
        UIAxes4_3                    matlab.ui.control.UIAxes
        UIAxes2_3                    matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes5_3                    matlab.ui.control.UIAxes
    end

    properties (Access = private)
        gravity_comp = false;
        vel_ref = false;
    end
    
    methods (Access = private)
    
        function step_analysis(app)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
            cla(app.UIAxes5)

            t = linspace(0, app.t_maxsSlider.Value, app.t_maxsSlider.Value*500);
            delta_t = t(2)-t(1);
            a_1 = 1; 
            a_2 = 1; 
            m_1 = 1;
            m_2 = 1;
            g = app.gms2EditField.Value;

            tau_max = 1000;
            
            target_theta_1 = app.q_10radEditField.Value*ones(1,length(t));
            target_theta_2 = app.q_20radEditField.Value*ones(1,length(t));

            K_p = [app.K_p1Slider.Value 0; 0 app.K_p2Slider.Value];
            K_d = [app.K_d1Slider.Value 0; 0 app.K_d2Slider.Value];
            K_i = [app.K_i1Slider.Value 0; 0 app.K_i2Slider.Value];

            e_prev = [0; 0];
            e_int = [0; 0];
            e_d  = [0; 0];

            theta_1_arr = zeros(1, length(t)+1);
            theta_2_arr = zeros(1, length(t)+1);
            theta_1_d_arr = zeros(1, length(t)+1);
            theta_2_d_arr = zeros(1, length(t)+1);
            theta_1_dd_arr = zeros(1, length(t));
            theta_2_dd_arr = zeros(1, length(t));
            tau_arr = zeros(2, length(t));

            for k = 1:length(t)
                e = [target_theta_1(k) - theta_1_arr(k); target_theta_2(k) - theta_2_arr(k)];
                e_d = [0 - theta_1_d_arr(k); 0 - theta_2_d_arr(k)];
                e_int = e_int + e* delta_t;
                if app.gravity_comp
                    gg = [m_1*g*a_1*0.5*cos(theta_1_arr(k))+m_2*g*(a_2*0.5*cos(theta_1_arr(k)+theta_2_arr(k))+a_1*cos(theta_1_arr(k)));...
                        m_2*g*a_2*0.5*cos(theta_1_arr(k)+theta_2_arr(k))];
                else
                    gg = [0,0]';
                end

                tau_unsat = K_p * e + K_d * e_d + K_i * e_int + gg;
            
                tau = max(min(tau_unsat, tau_max), -tau_max);
            
                q_dd = lab_7_dinamic_function(tau, theta_1_arr(k),...
                    theta_2_arr(k), theta_1_d_arr(k), theta_2_d_arr(k),...
                    a_1, a_2, m_1, m_2, g);
            
                theta_1_d = theta_1_d_arr(k) + q_dd(1) * delta_t;
                theta_2_d = theta_2_d_arr(k) + q_dd(2) * delta_t;
                theta_1 = theta_1_arr(k) + theta_1_d * delta_t;
                theta_2 = theta_2_arr(k) + theta_2_d * delta_t;
            
                theta_1_arr(k+1) = theta_1;
                theta_2_arr(k+1) = theta_2;
                theta_1_d_arr(k+1) = theta_1_d;
                theta_2_d_arr(k+1) = theta_2_d;
                theta_1_dd_arr(k) = q_dd(1);
                theta_2_dd_arr(k) = q_dd(2);
        
                tau_arr(:, k) = tau;
            end 

            plot(app.UIAxes5, t, theta_1_arr(1:end-1),'r');
            hold(app.UIAxes5, 'on')
            plot(app.UIAxes5, t,  theta_2_arr(1:end-1),'b');
            plot(app.UIAxes5, t,  target_theta_1,'r--');
            plot(app.UIAxes5, t,  target_theta_2,'b--');
            title(app.UIAxes5, 'Step response')
            grid(app.UIAxes5, 'on')
            xlabel(app.UIAxes5, 'Time [s]')
            ylabel(app.UIAxes5, '\theta [rad]')
            legend(app.UIAxes5, {'\theta_1','\theta_2'})

            plot(app.UIAxes, t, tau_arr,'r');
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, t,  tau_arr,'b');
            title(app.UIAxes, '\tau')
            grid(app.UIAxes, 'on')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, '\tau [Nm]')
            legend(app.UIAxes, {'\tau_1','\tau_2'})

            e_1 = target_theta_1' - theta_1_arr(2:end)';
            e_2 = target_theta_2' - theta_2_arr(2:end)';

            plot(app.UIAxes2, t, e_1,'r');
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2, t, e_2,'b');
            title(app.UIAxes2, '\theta_r - \theta')
            grid(app.UIAxes2, 'on')
            xlabel(app.UIAxes2, 'Time [s]')
            ylabel(app.UIAxes2, '\theta [rad]')
            legend(app.UIAxes2, {'\theta_{r1} - \theta_1','\theta_{r2} - \theta_1'})

            plot(app.UIAxes3, t, theta_1_d_arr(1:end-1),'r');
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3, t,  theta_2_d_arr(1:end-1),'b');
            title(app.UIAxes3, '$\mathbf{\dot{\theta}}$', 'Interpreter', 'latex')
            grid(app.UIAxes3, 'on')
            xlabel(app.UIAxes3, 'Time [s]')
            ylabel(app.UIAxes3, '$\ddot{\theta} [rad/s]$', 'Interpreter', 'latex')
            legend(app.UIAxes3, {'$\dot{\theta_1}$','$\dot{\theta_2}$'}, 'Interpreter', 'latex')

            plot(app.UIAxes4, t, theta_1_dd_arr,'r');
            hold(app.UIAxes4, 'on')
            plot(app.UIAxes4, t,  theta_2_dd_arr,'b');
            title(app.UIAxes4, '$\mathbf{\ddot{\theta}}$', 'Interpreter', 'latex')
            grid(app.UIAxes4, 'on')
            xlabel(app.UIAxes4, 'Time [s]')
            ylabel(app.UIAxes4, '$\ddot{\theta} [rad/s^2]$', 'Interpreter', 'latex')
            legend(app.UIAxes4, {'$\ddot{\theta_1}$','$\ddot{\theta_2}$'}, 'Interpreter', 'latex')
         
        end

        function trajectory_control(app)
            cla(app.UIAxes_3)
            cla(app.UIAxes5_3)
            cla(app.UIAxes4_4)
            cla(app.UIAxes4_3)
            cla(app.UIAxes2_4)
            cla(app.UIAxes2_3)
            [theta_lin, theta, xy, t_dis, t] = trajectory(app.t_maxsSlider_3.Value,app.a_maxms2Slider.Value,app.WStrajectoryCheckBox.Value);
            a_1 = 1; 
            a_2 = 1;
            delta_t = t(2)-t(1);
            
            m_1 = 1;
            m_2 = 1;
            g = app.gms2EditField.Value;

            tau_max = 1000;
            x = xy(1,:);
            y = xy(2,:);
            target_theta_1 = theta_lin(1,:);
            target_theta_2 = theta_lin(2,:);
            target_theta_1_d = zeros(1,length(t));
            target_theta_2_d = zeros(1,length(t));
            target_theta_1_dd = zeros(1,length(t));
            target_theta_2_dd = zeros(1,length(t));

            target_theta_1_d(2:end) = diff(target_theta_1) / delta_t;
            target_theta_2_d(2:end) = diff(target_theta_2) / delta_t;

            target_theta_1_dd(2:end) = diff(target_theta_1_d) / delta_t;
            target_theta_2_dd(2:end) = diff(target_theta_2_d) / delta_t;

            x0 = 0; y0 = 0;
            x1 = a_1 * cos(theta(1,1));
            y1 = a_1 * sin(theta(1,1));
            x2 = x1 + a_2 * cos(theta(1,1) + theta(2,1));
            y2 = y1 + a_2 * sin(theta(1,1) + theta(2,1));
            plot(app.UIAxes_3, x, y, 'r', 'LineWidth', 2, 'MarkerSize', 1)
            hold(app.UIAxes_3, 'on')
            grid(app.UIAxes_3, 'on')
            plot(app.UIAxes_3, [x0 x1], [y0 y1], 'k-o', 'LineWidth', 2, 'MarkerSize', 6)
            plot(app.UIAxes_3, [x1 x2], [y1 y2], 'k', 'LineWidth', 2, 'MarkerSize', 6)
            axis(app.UIAxes_3, 'equal')
            xlim(app.UIAxes_3, [-2, 2])
            ylim(app.UIAxes_3, [-2, 2])
            title(app.UIAxes_3, 'WS Trajectory')

            K_p = [app.K_p1Slider.Value 0; 0 app.K_p2Slider.Value];
            K_d = [app.K_d1Slider.Value 0; 0 app.K_d2Slider.Value];
            K_i = [app.K_i1Slider.Value 0; 0 app.K_i2Slider.Value];

            e_prev = [0; 0];
            e_int = [0; 0];
            e_d  = [0; 0];

            theta_1_arr = zeros(1, length(t)+1);
            theta_1_arr(1) = target_theta_1(1);
            theta_2_arr = zeros(1, length(t)+1);
            theta_2_arr(1) = target_theta_2(1);
            theta_1_d_arr = zeros(1, length(t)+1);
            theta_2_d_arr = zeros(1, length(t)+1);
            theta_1_dd_arr = zeros(1, length(t));
            theta_2_dd_arr = zeros(1, length(t));
            tau_arr = zeros(2, length(t));
            x_1_arr = zeros(1, length(t)+1);
            x_1_arr(1) = a_1 * cos(target_theta_1(1));
            x_2_arr = zeros(1, length(t)+1);
            x_2_arr(1) = x1 + a_2 * cos(target_theta_1(1) + target_theta_2(1));
            y_1_arr = zeros(1, length(t)+1);
            y_1_arr(1) = a_1 * sin(target_theta_1(1));
            y_2_arr = zeros(1, length(t)+1);
            y_2_arr(1) = y1 + a_2 * sin(target_theta_1(1) + target_theta_2(1));

            for k = 1:length(t)
                e = [target_theta_1(k) - theta_1_arr(k); target_theta_2(k) - theta_2_arr(k)];
                if app.vel_ref
                    e_d = [target_theta_1_d(k) - theta_1_d_arr(k); target_theta_2_d(k) - theta_2_d_arr(k)];
                else
                    e_d = [0 - theta_1_d_arr(k); 0 - theta_2_d_arr(k)];
                end
                e_int = e_int + e* delta_t;
                if app.gravity_comp
                    gg = [m_1*g*a_1*0.5*cos(theta_1_arr(k))+m_2*g*(a_2*0.5*cos(theta_1_arr(k)+theta_2_arr(k))+a_1*cos(theta_1_arr(k)));...
                        m_2*g*a_2*0.5*cos(theta_1_arr(k)+theta_2_arr(k))];
                else
                    gg = [0,0]';
                end

                tau_unsat = K_p * e + K_d * e_d + K_i * e_int + gg;

                tau = max(min(tau_unsat, tau_max), -tau_max);

                q_dd = lab_7_dinamic_function(tau, theta_1_arr(k),...
                    theta_2_arr(k), theta_1_d_arr(k), theta_2_d_arr(k),...
                    a_1, a_2, m_1, m_2, g);

                theta_1_d = theta_1_d_arr(k) + q_dd(1) * delta_t;
                theta_2_d = theta_2_d_arr(k) + q_dd(2) * delta_t;
                theta_1 = theta_1_arr(k) + theta_1_d * delta_t;
                theta_2 = theta_2_arr(k) + theta_2_d * delta_t;

                theta_1_arr(k+1) = theta_1;
                theta_2_arr(k+1) = theta_2;
                theta_1_d_arr(k+1) = theta_1_d;
                theta_2_d_arr(k+1) = theta_2_d;
                theta_1_dd_arr(k) = q_dd(1);
                theta_2_dd_arr(k) = q_dd(2);

                x1_arr(k) = a_1 * cos(theta_1);
                y1_arr(k) = a_1 * sin(theta_1);
                x2_arr(k) = x1_arr(k) + a_2 * cos(theta_1 + theta_2);
                y2_arr(k) = y1_arr(k) + a_2 * sin(theta_1 + theta_2);

                tau_arr(:, k) = tau;
            end 
            plot(app.UIAxes_3, x2_arr, y2_arr, 'b', 'LineWidth', 2, 'MarkerSize', 6)

            plot(app.UIAxes5_3, t, theta_1_arr(1:end-1),'r');
            hold(app.UIAxes5_3, 'on')
            plot(app.UIAxes5_3, t,  theta_2_arr(1:end-1),'b');
            plot(app.UIAxes5_3, t,  target_theta_1,'r--');
            plot(app.UIAxes5_3, t,  target_theta_2,'b--');
            plot(app.UIAxes5_2, t_dis,  theta(1,:), 'ro');
            plot(app.UIAxes5_2, t_dis,  theta(2,:), 'bo');
            title(app.UIAxes5_3, 'Step response')
            grid(app.UIAxes5_3, 'on')
            xlabel(app.UIAxes5_3, 'Time [s]')
            ylabel(app.UIAxes5_3, '\theta [rad]')
            legend(app.UIAxes5_3, {'\theta_1','\theta_2'})

            plot(app.UIAxes2_4, t, tau_arr,'r');
            hold(app.UIAxes2_4, 'on')
            plot(app.UIAxes2_4, t,  tau_arr,'b');
            title(app.UIAxes2_4, '\tau')
            grid(app.UIAxes2_4, 'on')
            xlabel(app.UIAxes2_4, 'Time [s]')
            ylabel(app.UIAxes2_4, '\tau [Nm]')
            legend(app.UIAxes2_4, {'\tau_1','\tau_2'})

            e_1 = target_theta_1' - theta_1_arr(2:end)';
            e_2 = target_theta_2' - theta_2_arr(2:end)';

            plot(app.UIAxes2_3, t, e_1,'r');
            hold(app.UIAxes2_3, 'on')
            plot(app.UIAxes2_3, t, e_2,'b');
            title(app.UIAxes2_3, '\theta_r - \theta')
            grid(app.UIAxes2_3, 'on')
            xlabel(app.UIAxes2_3, 'Time [s]')
            ylabel(app.UIAxes2_3, '\theta [rad]')
            legend(app.UIAxes2_3, {'\theta_{r1} - \theta_1','\theta_{r2} - \theta_1'})

            plot(app.UIAxes4_4, t, theta_1_d_arr(1:end-1),'r');
            hold(app.UIAxes4_4, 'on')
            plot(app.UIAxes4_4, t,  theta_2_d_arr(1:end-1),'b');
            title(app.UIAxes4_4, '$\mathbf{\dot{\theta}}$', 'Interpreter', 'latex')
            grid(app.UIAxes4_4, 'on')
            xlabel(app.UIAxes4_4, 'Time [s]')
            ylabel(app.UIAxes4_4, '$\ddot{\theta} [rad/s]$', 'Interpreter', 'latex')
            legend(app.UIAxes4_4, {'$\dot{\theta_1}$','$\dot{\theta_2}$'}, 'Interpreter', 'latex')

            plot(app.UIAxes4_3, t, theta_1_dd_arr,'r');
            hold(app.UIAxes4_3, 'on')
            plot(app.UIAxes4_3, t,  theta_2_dd_arr,'b');
            title(app.UIAxes4_3, '$\mathbf{\ddot{\theta}}$', 'Interpreter', 'latex')
            grid(app.UIAxes4_3, 'on')
            xlabel(app.UIAxes4_3, 'Time [s]')
            ylabel(app.UIAxes4_3, '$\ddot{\theta} [rad/s^2]$', 'Interpreter', 'latex')
            legend(app.UIAxes4_3, {'$\ddot{\theta_1}$','$\ddot{\theta_2}$'}, 'Interpreter', 'latex')
        end

        function plot_trajectory(app)
            cla(app.UIAxes_2)
            cla(app.UIAxes5_2)
            cla(app.UIAxes2_2)
            cla(app.UIAxes4_2)

            [theta_lin, theta, xy, t_dis, t] = trajectory(app.t_maxsSlider_3.Value,app.a_maxms2Slider.Value,app.WStrajectoryCheckBox.Value);
            a_1 = 1; 
            a_2 = 1;
            delta_t = t(2)-t(1);

            x = xy(1,:);
            y = xy(2,:);
            target_theta_1 = theta_lin(1,:);
            target_theta_2 = theta_lin(2,:);
            target_theta_1_d = zeros(1,length(t));
            target_theta_2_d = zeros(1,length(t));
            target_theta_1_dd = zeros(1,length(t));
            target_theta_2_dd = zeros(1,length(t));

            target_theta_1_d(2:end) = diff(target_theta_1) / delta_t;
            target_theta_2_d(2:end) = diff(target_theta_2) / delta_t;

            target_theta_1_dd(2:end) = diff(target_theta_1_d) / delta_t;
            target_theta_2_dd(2:end) = diff(target_theta_2_d) / delta_t;

            x0 = 0; y0 = 0;
            x1 = a_1 * cos(theta(1,1));
            y1 = a_1 * sin(theta(1,1));
            x2 = x1 + a_2 * cos(theta(1,1) + theta(2,1));
            y2 = y1 + a_2 * sin(theta(1,1) + theta(2,1));
            plot(app.UIAxes_2, x, y, 'r', 'LineWidth', 2, 'MarkerSize', 1)
            hold(app.UIAxes_2, 'on')
            grid(app.UIAxes_2, 'on')
            plot(app.UIAxes_2, [x0 x1], [y0 y1], 'k-o', 'LineWidth', 2, 'MarkerSize', 6)
            plot(app.UIAxes_2, [x1 x2], [y1 y2], 'k', 'LineWidth', 2, 'MarkerSize', 6)
            axis(app.UIAxes_2, 'equal')
            xlim(app.UIAxes_2, [-2, 2])
            ylim(app.UIAxes_2, [-2, 2])
            title(app.UIAxes_2, 'WS Trajectory')

            plot(app.UIAxes5_2, t, target_theta_1,'r');
            hold(app.UIAxes5_2, 'on')
            plot(app.UIAxes5_2, t,  target_theta_2, 'b');
            plot(app.UIAxes5_2, t_dis,  theta(1,:), 'ro');
            plot(app.UIAxes5_2, t_dis,  theta(2,:), 'bo');
            title(app.UIAxes5_2, 'JS Trajectory')
            grid(app.UIAxes5_2, 'on')
            xlabel(app.UIAxes5_2, 'Time [s]')
            ylabel(app.UIAxes5_2, '\theta [rad]')
            legend(app.UIAxes5_2, {'\theta_1','\theta_2'})

            plot(app.UIAxes2_2, t, target_theta_1_d, 'r'); 
            hold(app.UIAxes2_2, 'on');
            plot(app.UIAxes2_2, t, target_theta_2_d, 'b');
            title(app.UIAxes2_2, '$\dot{\theta}$', 'Interpreter', 'latex');
            xlabel(app.UIAxes2_2, 'Time [s]');
            ylabel(app.UIAxes2_2, '$\dot{\theta}$ [rad/s]', 'Interpreter', 'latex');
            legend(app.UIAxes2_2, {'$\dot{\theta_1}$','$\dot{\theta_2}$'}, 'Interpreter', 'latex');
            grid(app.UIAxes2_2, 'on');

            plot(app.UIAxes4_2, t(2:end), target_theta_1_dd(2:end), 'r'); 
            hold(app.UIAxes4_2, 'on');
            plot(app.UIAxes4_2, t(2:end), target_theta_2_dd(2:end), 'b');
            title(app.UIAxes4_2, '$\ddot{\theta}$', 'Interpreter', 'latex');
            xlabel(app.UIAxes4_2, 'Time [s]');
            ylabel(app.UIAxes4_2, '$\ddot{\theta}$ [rad/s]', 'Interpreter', 'latex');
            legend(app.UIAxes4_2, {'$\ddot{\theta_1}$','$\ddot{\theta_2}$'}, 'Interpreter', 'latex');
            grid(app.UIAxes4_2, 'on');


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.gravity_comp = false;
            app.GravitycompensationCheckBox.Value= false;
            app.vel_ref= false;
            app.RefvelocitysignalforfastresponseCheckBox.Value=false;
            step_analysis(app);
            plot_trajectory(app);
        end

        % Value changed function: K_i2Slider
        function K_i2SliderValueChanged(app, event)
            step_analysis(app)
            trajectory_control(app);
        end

        % Value changed function: t_maxsSlider
        function t_maxsSliderValueChanged(app, event)
            step_analysis(app)
            trajectory_control(app);
        end

        % Value changed function: a_maxms2Slider
        function a_maxms2SliderValueChanged(app, event)
            plot_trajectory(app);
            trajectory_control(app);
        end

        % Value changed function: t_maxsSlider_3
        function t_maxsSlider_3ValueChanged(app, event)
            plot_trajectory(app);
            trajectory_control(app);
        end

        % Value changed function: K_d2Slider
        function K_d2SliderValueChanged(app, event)
            step_analysis(app);
            trajectory_control(app);
        end

        % Value changed function: K_p2Slider
        function K_p2SliderValueChanged(app, event)
            step_analysis(app);
            trajectory_control(app);
        end

        % Value changed function: K_i1Slider
        function K_i1SliderValueChanged(app, event)
            step_analysis(app);
            trajectory_control(app);
        end

        % Value changed function: K_d1Slider
        function K_d1SliderValueChanged(app, event)
            step_analysis(app);
            trajectory_control(app);
        end

        % Value changed function: K_p1Slider
        function K_p1SliderValueChanged(app, event)
            step_analysis(app);
            trajectory_control(app);
        end

        % Value changed function: q_20radEditField
        function q_20radEditFieldValueChanged(app, event)
            step_analysis(app);
        end

        % Value changed function: q_10radEditField
        function q_10radEditFieldValueChanged(app, event)
            step_analysis(app);
        end

        % Value changed function: GravitycompensationCheckBox
        function GravitycompensationCheckBoxValueChanged(app, event)
            app.gravity_comp = app.GravitycompensationCheckBox.Value;
            step_analysis(app);
            trajectory_control(app);
        end

        % Value changed function: gms2EditField
        function gms2EditFieldValueChanged(app, event)
            step_analysis(app);
            trajectory_control(app);
        end

        % Value changed function: RefvelocitysignalforfastresponseCheckBox
        function RefvelocitysignalforfastresponseCheckBoxValueChanged(app, event)
            app.vel_ref = app.RefvelocitysignalforfastresponseCheckBox.Value;
            trajectory_control(app);
        end

        % Value changed function: WStrajectoryCheckBox
        function WStrajectoryCheckBoxValueChanged(app, event)
            plot_trajectory(app);
            trajectory_control(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StepresponseTab = uitab(app.TabGroup);
            app.StepresponseTab.Title = 'Step response';
            app.UIAxes5 = uiaxes(app.StepresponseTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.Panel_3 = uipanel(app.StepresponseTab);
            app.GridLayout = uigridlayout(app.Panel_3);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Panel = uipanel(app.StepresponseTab);
            app.t_maxsLabel = uilabel(app.Panel);
            app.t_maxsLabel.Interpreter = 'latex';
            app.t_maxsLabel.Text = '$t_{max}$ [s] =';
            app.t_maxsSlider = uislider(app.Panel);
            app.t_maxsSlider.Limits = [1 5];
            app.t_maxsSlider.ValueChangedFcn = createCallbackFcn(app, @t_maxsSliderValueChanged, true);
            app.t_maxsSlider.Value = 3;
            app.K_i2SliderLabel = uilabel(app.Panel);
            app.K_i2SliderLabel.Interpreter = 'latex';
            app.K_i2SliderLabel.Text = '$K_{i2}$ =';
            app.K_i2Slider = uislider(app.Panel);
            app.K_i2Slider.Limits = [0 400];
            app.K_i2Slider.ValueChangedFcn = createCallbackFcn(app, @K_i2SliderValueChanged, true);
            app.K_i2Slider.Value = 220;
            app.K_d2Slider_2Label = uilabel(app.Panel);
            app.K_d2Slider_2Label.Interpreter = 'latex';
            app.K_d2Slider_2Label.Text = '$K_{d2}$ =';
            app.K_d2Slider = uislider(app.Panel);
            app.K_d2Slider.ValueChangedFcn = createCallbackFcn(app, @K_d2SliderValueChanged, true);
            app.K_d2Slider.Value = 70;
            app.K_p2Slider_2Label = uilabel(app.Panel);
            app.K_p2Slider_2Label.Interpreter = 'latex';
            app.K_p2Slider_2Label.Text = '$K_{p2}$ =';
            app.K_p2Slider = uislider(app.Panel);
            app.K_p2Slider.Limits = [0 600];
            app.K_p2Slider.ValueChangedFcn = createCallbackFcn(app, @K_p2SliderValueChanged, true);
            app.K_p2Slider.Value = 420;
            app.K_i1Slider_2Label = uilabel(app.Panel);
            app.K_i1Slider_2Label.Interpreter = 'latex';
            app.K_i1Slider_2Label.Text = '$K_{i1}$ =';
            app.K_i1Slider = uislider(app.Panel);
            app.K_i1Slider.Limits = [0 400];
            app.K_i1Slider.ValueChangedFcn = createCallbackFcn(app, @K_i1SliderValueChanged, true);
            app.K_i1Slider.Value = 330;
            app.K_d1Slider_2Label = uilabel(app.Panel);
            app.K_d1Slider_2Label.Interpreter = 'latex';
            app.K_d1Slider_2Label.Text = '$K_{d1}$ =';
            app.K_d1Slider = uislider(app.Panel);
            app.K_d1Slider.ValueChangedFcn = createCallbackFcn(app, @K_d1SliderValueChanged, true);
            app.K_d1Slider.Value = 65;
            app.K_p1Slider_2Label = uilabel(app.Panel);
            app.K_p1Slider_2Label.Interpreter = 'latex';
            app.K_p1Slider_2Label.Text = '$K_{p1}$ =';
            app.K_p1Slider = uislider(app.Panel);
            app.K_p1Slider.Limits = [0 600];
            app.K_p1Slider.ValueChangedFcn = createCallbackFcn(app, @K_p1SliderValueChanged, true);
            app.K_p1Slider.Value = 520;
            app.gLabel = uilabel(app.Panel);
            app.gLabel.Interpreter = 'latex';
            app.gLabel.Text = '$g [m/s^2]$ =';
            app.gms2EditField = uieditfield(app.Panel, 'numeric');
            app.gms2EditField.ValueChangedFcn = createCallbackFcn(app, @gms2EditFieldValueChanged, true);
            app.gms2EditField.Value = 9.81;
            app.q_10radLabel_2 = uilabel(app.Panel);
            app.q_10radLabel_2.Interpreter = 'latex';
            app.q_10radLabel_2.Text = '$q_2(0) [rad]$ = ';
            app.q_20radEditField = uieditfield(app.Panel, 'numeric');
            app.q_20radEditField.ValueChangedFcn = createCallbackFcn(app, @q_20radEditFieldValueChanged, true);
            app.q_20radEditField.Value = 0.3927;
            app.q_10radEditFieldLabel = uilabel(app.Panel);
            app.q_10radEditFieldLabel.Interpreter = 'latex';
            app.q_10radEditFieldLabel.Text = '$q_1(0) [rad]$ = ';
            app.q_10radEditField = uieditfield(app.Panel, 'numeric');
            app.q_10radEditField.ValueChangedFcn = createCallbackFcn(app, @q_10radEditFieldValueChanged, true);
            app.q_10radEditField.Value = 0.7854;
            app.GravitycompensationCheckBox = uicheckbox(app.Panel);
            app.GravitycompensationCheckBox.ValueChangedFcn = createCallbackFcn(app, @GravitycompensationCheckBoxValueChanged, true);
            app.GravitycompensationCheckBox.Text = 'Gravity compensation';
            app.RefvelocitysignalforfastresponseCheckBox = uicheckbox(app.Panel);
            app.RefvelocitysignalforfastresponseCheckBox.ValueChangedFcn = createCallbackFcn(app, @RefvelocitysignalforfastresponseCheckBoxValueChanged, true);
            app.RefvelocitysignalforfastresponseCheckBox.Text = 'Ref. velocity signal for fast response';
            app.TrajectoryTab_2 = uitab(app.TabGroup);
            app.TrajectoryTab_2.Title = 'Trajectory';
            app.UIAxes5_2 = uiaxes(app.TrajectoryTab_2);
            title(app.UIAxes5_2, 'Title')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            zlabel(app.UIAxes5_2, 'Z')
            app.UIAxes_2 = uiaxes(app.TrajectoryTab_2);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel_4 = uipanel(app.TrajectoryTab_2);
            app.GridLayout_2 = uigridlayout(app.Panel_4);
            app.UIAxes2_2 = uiaxes(app.GridLayout_2);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes4_2 = uiaxes(app.GridLayout_2);
            title(app.UIAxes4_2, 'Title')
            xlabel(app.UIAxes4_2, 'X')
            ylabel(app.UIAxes4_2, 'Y')
            zlabel(app.UIAxes4_2, 'Z')
            app.Panel_5 = uipanel(app.TrajectoryTab_2);
            app.a_maxms2SliderLabel = uilabel(app.Panel_5);
            app.a_maxms2SliderLabel.Interpreter = 'latex';
            app.a_maxms2SliderLabel.Text = '$a_{max} [m/s^2]$ =';
            app.a_maxms2Slider = uislider(app.Panel_5);
            app.a_maxms2Slider.Limits = [1 50];
            app.a_maxms2Slider.ValueChangedFcn = createCallbackFcn(app, @a_maxms2SliderValueChanged, true);
            app.a_maxms2Slider.Value = 20;
            app.t_maxsSlider_3Label = uilabel(app.Panel_5);
            app.t_maxsSlider_3Label.Interpreter = 'latex';
            app.t_maxsSlider_3Label.Text = '$t_{max}$ [s] =';
            app.t_maxsSlider_3 = uislider(app.Panel_5);
            app.t_maxsSlider_3.Limits = [1 15];
            app.t_maxsSlider_3.ValueChangedFcn = createCallbackFcn(app, @t_maxsSlider_3ValueChanged, true);
            app.t_maxsSlider_3.Value = 6;
            app.WStrajectoryCheckBox = uicheckbox(app.Panel_5);
            app.WStrajectoryCheckBox.ValueChangedFcn = createCallbackFcn(app, @WStrajectoryCheckBoxValueChanged, true);
            app.WStrajectoryCheckBox.Text = 'W-S trajectory';
            app.RobotICControlTab = uitab(app.TabGroup);
            app.RobotICControlTab.Title = 'Robot IC Control';
            app.UIAxes5_3 = uiaxes(app.RobotICControlTab);
            title(app.UIAxes5_3, 'Title')
            xlabel(app.UIAxes5_3, 'X')
            ylabel(app.UIAxes5_3, 'Y')
            zlabel(app.UIAxes5_3, 'Z')
            app.UIAxes_3 = uiaxes(app.RobotICControlTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.Panel_6 = uipanel(app.RobotICControlTab);
            app.GridLayout_3 = uigridlayout(app.Panel_6);
            app.UIAxes2_3 = uiaxes(app.GridLayout_3);
            title(app.UIAxes2_3, 'Title')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes4_3 = uiaxes(app.GridLayout_3);
            title(app.UIAxes4_3, 'Title')
            xlabel(app.UIAxes4_3, 'X')
            ylabel(app.UIAxes4_3, 'Y')
            zlabel(app.UIAxes4_3, 'Z')
            app.UIAxes2_4 = uiaxes(app.GridLayout_3);
            title(app.UIAxes2_4, 'Title')
            xlabel(app.UIAxes2_4, 'X')
            ylabel(app.UIAxes2_4, 'Y')
            zlabel(app.UIAxes2_4, 'Z')
            app.UIAxes4_4 = uiaxes(app.GridLayout_3);
            title(app.UIAxes4_4, 'Title')
            xlabel(app.UIAxes4_4, 'X')
            ylabel(app.UIAxes4_4, 'Y')
            zlabel(app.UIAxes4_4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab_7_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end